classdef geogravgoce < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeoGravGOCEFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SGGPreprocessingTab             matlab.ui.container.Tab
        DisplayPanel_2                  matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        GrafLabPanel                    matlab.ui.container.Panel
        CreateInputFormatlatlonhButton  matlab.ui.control.Button
        RUNGrafLabButton                matlab.ui.control.Button
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        SGGPanel                        matlab.ui.container.Panel
        ExportdataPanel                 matlab.ui.container.Panel
        These2fileswillbesavedinyourworkingfolderautomaticallyLabel  matlab.ui.control.Label
        SGG_GRFmatLabel                 matlab.ui.control.Label
        SGG_GRF_ReporttxtLabel          matlab.ui.control.Label
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        StatisticsVijmatReporttxtButton  matlab.ui.control.Button
        Lamp_8                          matlab.ui.control.Lamp
        GravityGradientsButton          matlab.ui.control.Button
        Lamp_9                          matlab.ui.control.Lamp
        GOCEOrbitTrackButton            matlab.ui.control.Button
        Lamp_10                         matlab.ui.control.Lamp
        GOCEAltitudeButton              matlab.ui.control.Button
        Lamp_11                         matlab.ui.control.Lamp
        These2fileswillbesavedinyourworkingfolderautomaticallyLabel_7  matlab.ui.control.Label
        LoadDataafterParsingPanel       matlab.ui.container.Panel
        EGG_NOM_2Button                 matlab.ui.control.Button
        SST_PSO_2Button                 matlab.ui.control.Button
        CalculateSGGButton              matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        clearButton                     matlab.ui.control.Button
        GGMtransformationLNOFtoGRFTab   matlab.ui.container.Tab
        LoadDataforTransformationPanel  matlab.ui.container.Panel
        TransformLNOFtoGRFButton        matlab.ui.control.Button
        GGMfileButton                   matlab.ui.control.Button
        LampGGM                         matlab.ui.control.Lamp
        QuaternionsPSO_2_QATButton      matlab.ui.control.Button
        LampQAT                         matlab.ui.control.Lamp
        LoadthecorrespondingwiththefirsttabPSOQuaternionsFileLabel  matlab.ui.control.Label
        LamptransformLNOF2GRF           matlab.ui.control.Lamp
        ExporttransformeddatainGRFPanel  matlab.ui.container.Panel
        LampexportGGMGRF                matlab.ui.control.Lamp
        LampexportreportGGMGRF          matlab.ui.control.Lamp
        GGM_LNOF_2_GRFmatLabel          matlab.ui.control.Label
        GGM_LNOF_2_GRF_ReportLabel      matlab.ui.control.Label
        ThesefileswillbesavedinyourworkingfolderautomaticallyLabel_2  matlab.ui.control.Label
        StatisticsGGMVijinGRFmatReportButton  matlab.ui.control.Button
        LampexportGGMGRFstats           matlab.ui.control.Lamp
        FiguresofVijinGRFButton         matlab.ui.control.Button
        LampexportGGMGRFjpeg            matlab.ui.control.Lamp
        These2fileswillbesavedinyourworkingfolderautomaticallyLabel_8  matlab.ui.control.Label
        DisplayPanel                    matlab.ui.container.Panel
        UIAxes_Vzz_LNOF                 matlab.ui.control.UIAxes
        UIAxes_Vzz_EFRF                 matlab.ui.control.UIAxes
        UIAxes_Vzz_IRF                  matlab.ui.control.UIAxes
        UIAxes_Vzz_GRF                  matlab.ui.control.UIAxes
        ImporttheGGMgravitationaltensorcomponentsGrafLabsoutputLabel_2  matlab.ui.control.Label
        FilteringTab                    matlab.ui.container.Tab
        LoadReducedDataPanel            matlab.ui.container.Panel
        ReducedDataButton               matlab.ui.control.Button
        SSG_GRFGGM_LNOF_2_GRFButton     matlab.ui.control.Button
        SGG_GRF1sttabsoutputGGM_LNOF_2_GRF2sttabsoutputLabel  matlab.ui.control.Label
        SGG_GRF1sttabsoutputGGM_LNOF_2_GRF2sttabsoutputLabel_2  matlab.ui.control.Label
        SGG_GRF1sttabsoutputGGM_LNOF_2_GRF2sttabsoutputLabel_3  matlab.ui.control.Label
        SSG_GRFGGMgravitationaltensorButton  matlab.ui.control.Button
        SelectFilteringMethodPanel      matlab.ui.container.Panel
        IIRButton_2                     matlab.ui.control.Button
        WaveletButton_2                 matlab.ui.control.Button
        FIRButton_2                     matlab.ui.control.Button
        FIR                             matlab.ui.container.Panel
        UIAxes3_2                       matlab.ui.control.UIAxes
        PSDButton                       matlab.ui.control.Button
        FilteredReducedSGGButton        matlab.ui.control.Button
        StatisticsButton                matlab.ui.control.Button
        MagnitudeResponseButton         matlab.ui.control.Button
        DesignMethodFIRWindowHammingLabel  matlab.ui.control.Label
        Lamp_12                         matlab.ui.control.Lamp
        Lamp_13                         matlab.ui.control.Lamp
        Lamp_14                         matlab.ui.control.Lamp
        Lamp_15                         matlab.ui.control.Lamp
        Vij_FIR_ReporttxtLabel          matlab.ui.control.Label
        These2fileswillbesavedinyourworkingfolderautomaticallyLabel_2  matlab.ui.control.Label
        Vij_FIRmatLabel                 matlab.ui.control.Label
        Lamp_16                         matlab.ui.control.Lamp
        Lamp_17                         matlab.ui.control.Lamp
        IIR                             matlab.ui.container.Panel
        UIAxes3_3                       matlab.ui.control.UIAxes
        PSDButton_2                     matlab.ui.control.Button
        FilteredReducedSGGButton_2      matlab.ui.control.Button
        StatisticsButton_2              matlab.ui.control.Button
        MagnitudeResponseButton_2       matlab.ui.control.Button
        DesignMethodIIRButterworthLabel  matlab.ui.control.Label
        Lamp_18                         matlab.ui.control.Lamp
        Lamp_19                         matlab.ui.control.Lamp
        Lamp_20                         matlab.ui.control.Lamp
        Lamp_21                         matlab.ui.control.Lamp
        Vij_IIR_ReporttxtLabel          matlab.ui.control.Label
        These2fileswillbesavedinyourworkingfolderautomaticallyLabel_5  matlab.ui.control.Label
        Vij_IIRmatLabel                 matlab.ui.control.Label
        Lamp_22                         matlab.ui.control.Lamp
        Lamp_23                         matlab.ui.control.Lamp
        WaveletPanel                    matlab.ui.container.Panel
        WLMRAdecompositionreconstructionPanel  matlab.ui.container.Panel
        d1CheckBox                      matlab.ui.control.CheckBox
        d2CheckBox                      matlab.ui.control.CheckBox
        d3CheckBox                      matlab.ui.control.CheckBox
        d4CheckBox                      matlab.ui.control.CheckBox
        d5CheckBox                      matlab.ui.control.CheckBox
        d6CheckBox                      matlab.ui.control.CheckBox
        d7CheckBox                      matlab.ui.control.CheckBox
        d8CheckBox                      matlab.ui.control.CheckBox
        d9CheckBox                      matlab.ui.control.CheckBox
        d10CheckBox                     matlab.ui.control.CheckBox
        d11CheckBox                     matlab.ui.control.CheckBox
        d12CheckBox                     matlab.ui.control.CheckBox
        a12CheckBox                     matlab.ui.control.CheckBox
        WLMRAreconstructionButton       matlab.ui.control.Button
        WLMRAdecompositionButton        matlab.ui.control.Button
        Lamp_GG_WL_MRA_3                matlab.ui.control.Lamp
        Lamp_GG_WL_MRA_2                matlab.ui.control.Lamp
        OriginalbrownandreconstructedgreensignalPanel  matlab.ui.container.Panel
        UIAxes_WL                       matlab.ui.control.UIAxes
        UIAxes_WL_2                     matlab.ui.control.UIAxes
        Lamp_GG_WL_MRA                  matlab.ui.control.Lamp
        Lampexportreport_GG_WL_MRA      matlab.ui.control.Lamp
        GG_WLmatLabel                   matlab.ui.control.Label
        GG_WL_ReportmatLabel            matlab.ui.control.Label
        GGsafterWLMRAButton             matlab.ui.control.Button
        Lamp_GG_WL_MRA_plot             matlab.ui.control.Lamp
        statisticsGGWLmatReporttxtButton  matlab.ui.control.Button
        Lamp_stats_WL_MRA               matlab.ui.control.Lamp
        PSDsofGGsafterWLMRAButton       matlab.ui.control.Button
        Lamp_psd_GG_WL_MRA_plot         matlab.ui.control.Lamp
        These2fileswillbesavedinyourworkingfolderautomaticallyLabel_6  matlab.ui.control.Label
        SelectcoefficientsforsignalreconstructionLabel  matlab.ui.control.Label
        clearButton_2                   matlab.ui.control.Button
        RSsTransformationsTab           matlab.ui.container.Tab
        TransformationGRFtoLNOFPanel    matlab.ui.container.Panel
        TransformationGRFtoLNOFButton   matlab.ui.control.Button
        LoaddataforGRFtoLNOFtransformationButton  matlab.ui.control.Button
        LampdataGRFtoLNOF               matlab.ui.control.Lamp
        LamptransformationGRFtoLNOF     matlab.ui.control.Lamp
        ImporttheGGMgravitationaltensorcomponentsGrafLabsoutputLabel_3  matlab.ui.control.Label
        StatisticsofthedatainLNOFmatReportButton  matlab.ui.control.Button
        LampstatstransfLNOF             matlab.ui.control.Lamp
        These2fileswillbesavedinyourworkingfolderautomaticallyLabel_9  matlab.ui.control.Label
        FiguresofthedatainLNOFButton    matlab.ui.control.Button
        LampfigtransfLNOF               matlab.ui.control.Lamp
        DisplayPanel_3                  matlab.ui.container.Panel
        UIAxes_Vzz_LNOF_3               matlab.ui.control.UIAxes
        UIAxes_Vzz_GRF_3                matlab.ui.control.UIAxes
        DisplayPanel_4                  matlab.ui.container.Panel
        UIAxes_Vzz_LNOF_4               matlab.ui.control.UIAxes
        UIAxes_Vzz_GRF_4                matlab.ui.control.UIAxes
        TransformationLNOFtoGRFPanel    matlab.ui.container.Panel
        TransformationLNOFtoGRFButton   matlab.ui.control.Button
        LoaddataforLNOFtoGRFtransformationButton  matlab.ui.control.Button
        LampdataLNOFtoGRF               matlab.ui.control.Lamp
        LamptransformationLNOFtoGRF     matlab.ui.control.Lamp
        StatisticsofthedatainGRFmatReportButton  matlab.ui.control.Button
        These2fileswillbesavedinyourworkingfolderautomaticallyLabel_10  matlab.ui.control.Label
        FiguresofthedatainGRFButton     matlab.ui.control.Button
        ImporttheGGMgravitationaltensorcomponentsGrafLabsoutputLabel_4  matlab.ui.control.Label
        LampstatstransfGRF              matlab.ui.control.Lamp
        LampFIGtransfGRF                matlab.ui.control.Lamp
        GravLabAUTh2020Label            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        % initialize  Variables
        % needed for: passing values between call backs functions
        NOM_data=[]; %  NOM data
        NOM_path=[]; % 
        PSO_data=[]; %  PSO.kin data
        PSO_path=[]; % data's name
        GG_coords_GRF=[]; %  SGG output
        data_for_filtering=[]; %  data for filtering
        filt_data=[]; %  data after filtering
        N=[]; % filter order
        b=[]; % b of FIR filter
        a=[]; % a of IIR filter
        cur_Folder=[]; % user's path
        GGM_data=[]; % GGM data
        GGM_path=[]; % GGM's path
        PSO_QAT_data=[]; % PSO.QAT data
        PSO_QAT_data_path=[]; % PSO.QAT data's path
        GG_LNOF_2_GRF=[]; % save the first output
        GGM_LNOF_2_GRF=[]; % output lnof to grf
        GGM_GRF=[];
        VIRFgradients=[];
        VEFRFgradients=[];
        VLNOF_gradients=[];
        VGRF_gradients=[];
        GG_GRF_data=[];
        GG_LNOF_data=[];
        % wavelets
        wave_reconstructed=[];
        wave_decomposed=[];
        GG_WL=[];
        datafordecomposition1=[];
        datafordecomposition2=[];
        counter_GG_WL_MRA=0;
        counter_stats_GG_WL_MRA_Vij=0;
        counter_GG_WL_MRA_plot=0;
        counter_psd_GG_WL_MRA_plot=0;
        
        % counters
        counter_NOM=0; % counter for NOM data
        counter_PSO=0; % counter for NOM data
        counter_GG=0; % counter for Gravity Gradients plots
        counter_graflab=0; % counter for GrafLab input format
        counter_stats_1_GRF=0; % counter for statistics (pre-processing tab)
        counter_altitude=0; % conunter for GOCE Altitude plots
        counter_orbit=0; % counter for GOCE Orbit track plots
        counter_GGM=0; % counter for GGM data
        counter_PSO_QAT=0; % counter for PSO_QAT data
        counter_GG_GGM=0; % counter for plot of GGM Gravity Gradients in GRF
        counter_stats_GGM_GRF=0; % counter for stats of GGM Gravity Gradients in GRF
        counter_GG_LNOF=0;
        counter_GG_GRF=0;
        counter_stats_GGs_2_LNOF=0;
        counter_stats_GGs_2_GRF=0;
        counter_GG_LNOF_2=0
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EGG_NOM_2Button
        function EGG_NOM_2ButtonPushed(app, event)
            clc        
            % passing values between between callbacks
            app.counter_PSO=app.counter_PSO; 
            app.counter_NOM=app.counter_NOM;
            app.NOM_data=app.NOM_data;
            app.NOM_data=[];
            
            % load the EGG_NOM_2_data (gravity gradients in GRF, in ASCII format)
            [filename,path] = uigetfile( ...
                {'*.sgg','sgg files (*.sgg)';
                '*.*',  'All Files (*.*)'}, ...
                'Select EGG_NOM_2 Files (One or More Files)',...
                'MultiSelect', 'on');
            
            if isequal(filename, 0) % cancel button checked
                app.Lamp.Color= 'red'; % visualization: red lamp
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'The cancel button was selected.';'Please, load the EGG NOM 2 data.'}, 'NOM data ', 'help', Opt);
            else
                app.NOM_data=strcat(filename);  
                app.NOM_path=path;
                
                if isempty(app.NOM_data)==0
                    app.counter_NOM=app.counter_NOM +1;
                    app.Lamp.Color= 'Green'; % visualization: green lamp
                    if (app.counter_PSO==1)
                        app.CalculateSGGButton.Enable='on'; % enable pushbutton
                    end
                else
                    Opt.Interpreter = 'tex';
                    Opt.WindowStyle = 'normal';
                    msgbox({'Please, load the EGG NOM 2 data.'}, 'NOM data ', 'help', Opt);
                end
            end
        end

        % Button pushed function: SST_PSO_2Button
        function SST_PSO_2ButtonPushed(app, event)
           clc
            % passing values between between callbacks
            app.counter_NOM=app.counter_NOM; 
            app.counter_PSO=app.counter_PSO;
            app.PSO_data=app.PSO_data;
            app.PSO_data=[];
            
            % load the PSO_2_data (precise science orbits, in ASCII format)
            [filename,path] = uigetfile( ...
                {'*.kin','Kinematic files (*.kin)';
                '*.*',  'All Files (*.*)'}, ...
                'Select PSO_2 Files (One or More Files)',...
                'MultiSelect', 'on');
                     
            if isequal(filename, 0) % cancel button checked
                app.Lamp_2.Color= 'red'; % visualization: red lamp
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'The cancel button was selected.';'Please, load the SST PSO 2 data.'}, ' PSO data ', 'help', Opt);
            else
                app.PSO_data=strcat(filename);  
                app.PSO_path=path;
                
                if isempty(app.PSO_data)==0
                    app.counter_PSO=app.counter_PSO +1;
                    app.Lamp_2.Color= 'Green'; % visualization: green lamp
                    if (app.counter_NOM==1)
                        app.CalculateSGGButton.Enable='on'; % enable pushbutton
                    end
                else
                    Opt.Interpreter = 'tex';
                    Opt.WindowStyle = 'normal';
                    msgbox({'Please, load the PSO 2 data.'}, 'PSO data ', 'help', Opt);
                end             
            end  
        end

        % Button pushed function: CalculateSGGButton
        function CalculateSGGButtonPushed(app, event)
            clc
            tic
            % message for the user
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Processing your data, Please wait.')
            f = waitbar(0,'Please wait...');
            pause(2)
            waitbar(.50,f,'Processing your data');
            pause(2)
            waitbar(1,f,'Please wait...');
            pause(2)
            close(f)
            
            % passing values between callbacks
            app.NOM_data=app.NOM_data;
            app.PSO_data=app.PSO_data;
            app.GG_coords_GRF=app.GG_coords_GRF;
            app.cur_Folder=app.cur_Folder;
            counter_NOM_0=app.counter_NOM;
            counter_PSO_0=app.counter_PSO;
            NOM_data_0=app.NOM_data; % contains NOM info
            PSO_data_0=app.PSO_data; % contains PSO info
            app.counter_NOM=app.counter_NOM;
            app.counter_PSO=app.counter_PSO;
            app.NOM_path=app.NOM_path;
            app.PSO_path=app.PSO_path;
            path_NOM=app.NOM_path;
            path_PSO=app.PSO_path;
                   
            
            % function: Single VS Multi processing
            [app.GG_coords_GRF,stop_process,~] = single_vs_multi_processing(NOM_data_0,counter_NOM_0,PSO_data_0,counter_PSO_0,path_NOM,path_PSO); % ~ = type_processing
            time_res=toc; % measure time (sec)
            
            if (stop_process==1)
                % message for user
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'NOM files are not related to the PSO files.';'';'Please check them and rerun the code!';'' ;'NOTE: NOM(i) needs PSO(i-1)'}, 'Message for user', 'error', Opt);
                
                % visualization: grey lamps
                app.Lamp.Color= [0.65 0.65 0.65];
                app.Lamp_2.Color= [0.65 0.65 0.65];
                app.CalculateSGGButton.Enable='off';
                
                % initialize again
                app.counter_NOM=0;
                app.counter_PSO=0;
                app.NOM_data=[];
                app.PSO_data=[];
                app.GG_coords_GRF=[];
                
            elseif (stop_process==0)
                % message for the user
                clc
                fprintf(2, '\nGeoGravGOCE Info:\n')
                fprintf('processing time: %s\n',string(time_res))
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Your results were saved in the "SGG Pre-processing" folder.';'';' Check them!'}, 'End of processing', 'help', Opt);
                
                % visualization: green lamps
                app.Lamp_3.Color= 'Green';
                app.Lamp_4.Color= 'Green';
                app.Lamp_5.Color= 'Green';
                
                % colors
                r1=[52 169 131]/255; % green RGB
                r2=[69 51 170]/255; % blue RGB
                r3=[166 55 94]/255; % purple RBG
                
                % Display the data in the panel
                % plot Vzz- GRF
                app.UIAxes.Visible='on';
                Vzz = cell2mat(app.GG_coords_GRF{6,1}(1));
                time_utc_nom_fractional_new = app.GG_coords_GRF{11,1}{1};
                plot(app.UIAxes,time_utc_nom_fractional_new,Vzz,"Color",r1);
                
                % plot Vyy- GRF
                app.UIAxes_4.Visible='on';
                Vyy = cell2mat(app.GG_coords_GRF{5,1}(1));
                time_utc_nom_fractional_new = app.GG_coords_GRF{11,1}{1};
                plot(app.UIAxes_4,time_utc_nom_fractional_new,Vyy,"Color",r2);
                
                % plot altitude- GRF
                app.UIAxes_3.Visible='on';
                h = cell2mat(app.GG_coords_GRF{3,1}(1));
                time_utc_nom_fractional_new = app.GG_coords_GRF{11,1}{1};
                plot(app.UIAxes_3,time_utc_nom_fractional_new,h,"Color",r3);
                
                % enable pushButtons
                app.SGG_GRFmatLabel.Enable='on';
                app.SGG_GRF_ReporttxtLabel.Enable='on';
                app.CreateInputFormatlatlonhButton.Enable='on';
                app.RUNGrafLabButton.Enable='on';
                app.StatisticsVijmatReporttxtButton.Enable='on';
                app.GravityGradientsButton.Enable='on';
                app.GOCEOrbitTrackButton.Enable='on';
                app.GOCEAltitudeButton.Enable='on';
                %app.TransformLNOFtoGRFButton.Enable='on';
                app.GGMfileButton.Enable='on';
                %app.QuaternionsPSO_2_QATButton.Enable='on';
                app.EGG_NOM_2Button.Enable='off';
                app.SST_PSO_2Button.Enable='off';
                
                % identify user's path for saving the outputs
                app.cur_Folder = pwd;
            end
        end

        % Button pushed function: StatisticsVijmatReporttxtButton
        function StatisticsVijmatReporttxtButtonPushed(app, event)
            clc
            % passing values between callbacks
            app.counter_stats_1_GRF=app.counter_stats_1_GRF+1; % counter for checking the stats
            app.cur_Folder=app.cur_Folder; % user's path
            currentFolder=app.cur_Folder;
            
            % function: calculate & save the stats of Vij
            [ ~ ] = stats_GRF( app.GG_coords_GRF,currentFolder);
            
            % visualization: green lamp
            if (app.counter_stats_1_GRF==1)
                app.Lamp_8.Color= 'Green';
            end
        end

        % Button pushed function: GravityGradientsButton
        function GravityGradientsButtonPushed(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Please wait, the figures are created.')
            
            % passing values between callbacks
            app.counter_GG=app.counter_GG +1; % counter for checking the plots
            app.GG_coords_GRF=app.GG_coords_GRF; % SGG output
            app.cur_Folder=app.cur_Folder; % user's path
            currentFolder=app.cur_Folder;
            
            % function: plots
            [~] = plot_EGG_NOM_2( app.GG_coords_GRF,currentFolder); %
            
            % visualization: green lamp
            if (app.counter_GG==1)
                app.Lamp_9.Color= 'Green';
            end
        end

        % Button pushed function: GOCEOrbitTrackButton
        function GOCEOrbitTrackButtonPushed(app, event)
            clc
            % passing values between between callbacks
            app.counter_orbit=app.counter_orbit +1; % counter for checking the plots
            app.GG_coords_GRF=app.GG_coords_GRF; % SGG output
            app.cur_Folder=app.cur_Folder; % user's path
            currentFolder=app.cur_Folder;
            
            % function: plot GOCE orbit track
            tic
            [ k ] = GOCE_orbit_track( app.GG_coords_GRF,currentFolder);
            figure_time= toc;
            
            % visualization: green lamp
            if (k==0)
                app.Lamp_10.Color= 'Green';
                fprintf('processing time: %s\n',string(figure_time))
            end
        end

        % Button pushed function: GOCEAltitudeButton
        function GOCEAltitudeButtonPushed(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Please wait, the figures are created.')
            
            % passing values between between callbacks
            app.GG_coords_GRF=app.GG_coords_GRF; % SGG output
            app.cur_Folder=app.cur_Folder; % user's path
            currentFolder=app.cur_Folder;
            
            % function: plot GOCE altitude
            [ k ] = altitude_GOCE_GRF( app.GG_coords_GRF,currentFolder);
            
            % visualization: green lamp
            if (k==1)
                app.Lamp_11.Color= 'Green';
            end
        end

        % Button pushed function: CreateInputFormatlatlonhButton
        function CreateInputFormatlatlonhButtonPushed(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Please wait, GrafLab input format is created.')
            
            % passing values between between callbacks
            app.GG_coords_GRF=app.GG_coords_GRF; % SGG output
            GG_coords_GRF1=app.GG_coords_GRF; % needed for the input of the "input_graflab" function
            app.cur_Folder=app.cur_Folder; % user's path
            currentFolder=app.cur_Folder;
            
            % function: create input format (lat,lon,h) for graflab software
            [ ~ ] = input_graflab( GG_coords_GRF1,currentFolder);
            app.counter_graflab=app.counter_graflab+1; % counter for graflab
            
            % visualization: green lamp
            if (app.counter_graflab==1)
                app.Lamp_6.Color= 'Green';
            end
            clc
        end

        % Button pushed function: RUNGrafLabButton
        function RUNGrafLabButtonPushed(app, event)
            clc
            % function: computes the gravitational tensor components in the LNOF
            % via "GrafLab" software for multiple input files
            [k,stop_counter] = graflab_gui_geograv();
            
            if (k==0 && stop_counter==0)
                % message for the user
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Your results were saved in the working folder.'}, 'End of processing', 'help', Opt);
                
                % visualization: green lamp
                app.Lamp_7.Color= 'Green';
            end
        end

        % Button pushed function: GGMfileButton
        function GGMfileButtonPushed(app, event)
             clc
            % load the GGM_data
            [filename,path] = uigetfile( ...
                {'*.txt','GGM in LNOF files (*.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Select GGM in LNOF Files (One or More Files)',...
                'MultiSelect', 'on');
            
            if isequal(filename, 0) % cancel button checked
                app.Lamp_2.Color= 'red'; % visualization: red lamp
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'The cancel button was selected.';'Please, load the GGM data.'}, ' GGM data ', 'help', Opt);
            else
                app.GGM_data=strcat(filename);  
                app.GGM_path=path;
                addpath(path);
                
            % Checking if data were loaded
            if isempty(app.GGM_data)==0
                app.counter_GGM=app.counter_GGM +1;
                app.LampGGM.Color= 'Green';
                app.QuaternionsPSO_2_QATButton.Enable='on';
            else
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Please, load the GGM data.'}, 'GGM data ', 'help', Opt);
            end
            
            end
            
        end

        % Button pushed function: QuaternionsPSO_2_QATButton
        function QuaternionsPSO_2_QATButtonPushed(app, event)
            clc
            % load the PSO_2_QAT_data (Quaternions)
            [filename,path] = uigetfile( ...
                {'*.qat','Quaternion files (*.qat)';
                '*.*',  'All Files (*.*)'}, ...
                'Select PSO_2_QAT Files (One or More Files)',...
                'MultiSelect', 'on');
            
                if isequal(filename, 0) % cancel button checked
                app.Lamp_2.Color= 'red'; % visualization: red lamp
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'The cancel button was selected.';'Please, load the PSO QAT data.'}, ' PSO QAT data ', 'help', Opt);
            else
            
            app.PSO_QAT_data=strcat(filename);
            app.PSO_QAT_data_path=path;
            addpath(path);
            
            % Checking if data were loaded
            if isempty(app.PSO_QAT_data)==0
                app.counter_PSO_QAT=app.counter_PSO_QAT +1;
                app.LampQAT.Color= 'Green';
                app.TransformLNOFtoGRFButton.Enable='on';
            else
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Please, load the PSO QAT data.'}, 'PSO QAT data ', 'help', Opt);
            end
                end
                
        end

        % Button pushed function: TransformLNOFtoGRFButton
        function TransformLNOFtoGRFButtonPushed(app, event)
            clc
            tic
            
            % message for the user
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Processing your data, Please wait.')
            f = waitbar(0,'Please wait...');
            pause(2)
            waitbar(.50,f,'Processing your data');
            pause(2)
            waitbar(1,f,'Please wait...');
            pause(2)
            close(f)
            
            % Show the public variables
            app.GGM_data=app.GGM_data;
            app.PSO_QAT_data=app.PSO_QAT_data;
            app.PSO_QAT_data_path=app.PSO_QAT_data_path;
            app.GGM_path=app.GGM_path;
            app.GG_coords_GRF=app.GG_coords_GRF;
            app.GGM_LNOF_2_GRF=app.GGM_LNOF_2_GRF;
            app.GG_LNOF_2_GRF=app.GG_LNOF_2_GRF;
            app.cur_Folder=app.cur_Folder; % user's path
            
            % counters
            app.counter_GGM=app.counter_GGM;
            app.counter_PSO_QAT=app.counter_PSO_QAT;
            GGM_c=app.counter_GGM;
            PSO_QAT_c=app.counter_PSO_QAT;
            counter_GG_LNOF_2_GRF=0;
            counter_GGM_0=app.counter_GGM;
            counter_PSO_QAT_0=app.counter_PSO_QAT;
            
            % needed for the ggm_transform_parallel function
            GGM_data_0=app.GGM_data;
            PSO_QAT_data_0=app.PSO_QAT_data;
            datafortransform=app.GG_coords_GRF;
            
            %  Multi processing function for transformation
            [app.GGM_LNOF_2_GRF,close_ggm]=ggm_transform_parallel(datafortransform, GGM_data_0, counter_GGM_0, PSO_QAT_data_0, counter_PSO_QAT_0, GGM_c, PSO_QAT_c);
            
            if (close_ggm==1)
                % message for the user
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'The loaded GGM and PSO QAT files do not correspond to the files loaded from the first tab'}, 'Message for user', 'error', Opt);
                
                % visualization: grey lamps
                app.LampGGM.Color= [0.65 0.65 0.65];
                app.LampQAT.Color= [0.65 0.65 0.65];
                app.LamptransformLNOF2GRF.Color= [0.65 0.65 0.65];
                app.LampexportGGMGRF.Color= [0.65 0.65 0.65];
                app.LampexportreportGGMGRF.Color= [0.65 0.65 0.65];
                
                % clear axes
                app.UIAxes_Vzz_LNOF.Visible='off';
                app.UIAxes_Vzz_EFRF.Visible='off';
                app.UIAxes_Vzz_IRF.Visible='off';
                app.UIAxes_Vzz_GRF.Visible='off';
                cla(app.UIAxes_Vzz_LNOF);
                cla(app.UIAxes_Vzz_EFRF);
                cla(app.UIAxes_Vzz_IRF);
                cla(app.UIAxes_Vzz_GRF);
                
            elseif (close_ggm==0)
                
                counter_GG_LNOF_2_GRF=counter_GG_LNOF_2_GRF+1;
                time_res=toc;
                
                % message for the user
                clc
                fprintf(2, '\nGeoGravGOCE Info:\n')
                fprintf('processing time: %s\n',string(time_res))
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Your results were saved in the "GGM Transformations" folder.'}, 'End of processing', 'help', Opt);
                
                % visualization: green lamps
                if (counter_GG_LNOF_2_GRF==1)
                    app.LamptransformLNOF2GRF.Color= 'Green';
                    app.LampexportGGMGRF.Color= 'Green';
                    app.LampexportreportGGMGRF.Color= 'Green';
                end
                
                if (counter_GG_LNOF_2_GRF==1)
                    app.LampexportGGMGRF.Color= 'Green';
                    app.LampexportreportGGMGRF.Color= 'Green';
                end
                
                % colors
                r1=[248 124 33]/255; % orange RGB
                r2=[53 227 128]/255; % green RGB
                r3=[111 54 226]/255; % blue RBG
                r4=[222 58 128]/255; % pink RBG
                
                % display the data in the panel
                % plot Vzz-LNOF
                app.UIAxes_Vzz_LNOF.Visible='on';
                Vzz = cell2mat(app.GGM_LNOF_2_GRF{26,1}(1));
                time_utc_nom_fractional_new = app.GG_coords_GRF{11,1}(1);
                plot(app.UIAxes_Vzz_LNOF,time_utc_nom_fractional_new{1},Vzz,"Color",r1);
                
                % plot Vzz-EFRF
                app.UIAxes_Vzz_EFRF.Visible='on';
                Vzz = cell2mat(app.GGM_LNOF_2_GRF{20,1}(1));
                time_utc_nom_fractional_new = app.GG_coords_GRF{11,1}(1);
                plot(app.UIAxes_Vzz_EFRF,time_utc_nom_fractional_new{1},Vzz,"Color",r4);
                
                % plot Vzz-IRF
                app.UIAxes_Vzz_IRF.Visible='on';
                Vzz = cell2mat(app.GGM_LNOF_2_GRF{14,1}(1));
                time_utc_nom_fractional_new = app.GG_coords_GRF{11,1}(1);
                plot(app.UIAxes_Vzz_IRF,time_utc_nom_fractional_new{1},Vzz,"Color",r3)
                
                % plot Vzz-GRF
                app.UIAxes_Vzz_GRF.Visible='on';
                Vzz = cell2mat(app.GGM_LNOF_2_GRF{8,1}(1));
                time_utc_nom_fractional_new = app.GG_coords_GRF{11,1}(1);
                plot(app.UIAxes_Vzz_GRF,time_utc_nom_fractional_new{1},Vzz,"Color",r2);
                
                % enable push Buttons
                app.StatisticsGGMVijinGRFmatReportButton.Enable='on';
                app.FiguresofVijinGRFButton.Enable='on';
                
                % Identify user's path
                app.cur_Folder = pwd;
            end
        end

        % Button pushed function: 
        % StatisticsGGMVijinGRFmatReportButton
        function StatisticsGGMVijinGRFmatReportButtonPushed(app, event)
            clc
            % passing values between callbacks
            app.counter_stats_GGM_GRF=app.counter_stats_GGM_GRF+1;
            app.cur_Folder=app.cur_Folder;
            currentFolder=app.cur_Folder;
            
            % function: calculation of GGM statistics
            [ ~ ] = stats_GGM_GRF( app.GGM_LNOF_2_GRF,currentFolder);
            
            % visualization: green lamp
            if (app.counter_stats_GGM_GRF==1)
                app.LampexportGGMGRFstats.Color= 'Green';
            end
        end

        % Button pushed function: FiguresofVijinGRFButton
        function FiguresofVijinGRFButtonPushed(app, event)
            clc
            % passing values between callbacks
            app.counter_GG_GGM=app.counter_GG_GGM +1; % counter for checking the plots
            app.GGM_LNOF_2_GRF=app.GGM_LNOF_2_GRF; % SGG output
            
            % function: plot Vij in GRF
            [~] = plot_EGG_GGM_GRF( app.GGM_LNOF_2_GRF);
            
            % visualization: green lamp
            if (app.counter_GG_GGM==1)
                app.LampexportGGMGRFjpeg.Color= 'Green';
            end
        end

        % Button pushed function: ReducedDataButton
        function ReducedDataButtonPushed(app, event)
            clc
            % passing values between callbacks
            app.data_for_filtering=app.data_for_filtering;
            
            % load user's reduced data (SGG-GGM)
            [app.data_for_filtering,k] = load_data_filtering();
            
            if (k==1)
                % visualization
                app.ReducedDataButton.BackgroundColor=[0.18,0.13,0.66]; % blue
                app.ReducedDataButton.FontColor=[1,1,1]; % white
                % other 2 options
                app.SSG_GRFGGM_LNOF_2_GRFButton.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.SSG_GRFGGM_LNOF_2_GRFButton.FontColor=[0,0,0]; % black
                app.SSG_GRFGGMgravitationaltensorButton.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.SSG_GRFGGMgravitationaltensorButton.FontColor=[0,0,0]; % black
                
                % enable the pushbuttons: FIR, IIR, Wavelet
                app.IIRButton_2.Enable='on';
                app.FIRButton_2.Enable='on';
                app.WaveletButton_2.Enable='on';
            else
                % visualization
                app.ReducedDataButton.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.ReducedDataButton.FontColor=[0,0,0]; % black
            end
        end

        % Button pushed function: SSG_GRFGGM_LNOF_2_GRFButton
        function SSG_GRFGGM_LNOF_2_GRFButtonPushed(app, event)
            clc
            % passing values between callbacks
            app.data_for_filtering=app.data_for_filtering;
            
            % function: load SGG_GRF.mat and GGM_LNOF_2_GRF.mat
            [app.data_for_filtering,k,q] = load_1_2_gui_output();
            
            % message for the user
            if ((k==0) || (q==0))
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Please, reload the [SGG GRF] & [GGM LNOF 2 GRF] data.'}, '[SGG_GRF] & [GGM_LNOF_2_GRF] data ', 'help', Opt);
            elseif ((k==1) && (q==1))
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'SGG data was successfully loaded and reduced.';'';' Please, select the Filtering Method.'}, 'Info', 'help', Opt);
                
                % visualization
                app.SSG_GRFGGM_LNOF_2_GRFButton.BackgroundColor=[0.18,0.13,0.66]; % blue
                app.SSG_GRFGGM_LNOF_2_GRFButton.FontColor=[1,1,1]; % white
                % other 2 options
                app.ReducedDataButton.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.ReducedDataButton.FontColor=[0,0,0]; % black
                app.SSG_GRFGGMgravitationaltensorButton.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.SSG_GRFGGMgravitationaltensorButton.FontColor=[0,0,0]; % black
                
                % enable the pushbuttons: FIR, IIR, Wavelet
                app.IIRButton_2.Enable='on';
                app.FIRButton_2.Enable='on';
                app.WaveletButton_2.Enable='on';
            end
            
        end

        % Button pushed function: 
        % SSG_GRFGGMgravitationaltensorButton
        function SSG_GRFGGMgravitationaltensorButtonPushed(app, event)
            clc
            % passing values between callbacks
            app.data_for_filtering=app.data_for_filtering;
            
            % function: load SGG.mat and user's GGM tensor components
            [app.data_for_filtering,k,q] = load_3rd_option_for_filtering();
            
            % message for user
            if ((k==0) || (q==0))
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Please, reload the [SGG GRF] & [GGM LNOF 2 GRF] data.'}, '[SGG_GRF] & [GGM_LNOF_2_GRF] data ', 'help', Opt);
            elseif ((k==1) && (q==1))
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'SGG data was successfully loaded and reduced.';'';' Please, select the Filtering Method.'}, 'Info', 'help', Opt);
                
                % visualization
                app.SSG_GRFGGMgravitationaltensorButton.BackgroundColor=[0.18,0.13,0.66]; % blue
                app.SSG_GRFGGMgravitationaltensorButton.FontColor=[1,1,1]; % white
                % other 2 options
                app.ReducedDataButton.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.ReducedDataButton.FontColor=[0,0,0]; % black
                app.SSG_GRFGGM_LNOF_2_GRFButton.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.SSG_GRFGGM_LNOF_2_GRFButton.FontColor=[0,0,0]; % black
                
                % enable the pushbuttons FIR, IIR, wavelet
                app.IIRButton_2.Enable='on';
                app.FIRButton_2.Enable='on';
                app.WaveletButton_2.Enable='on';
            end
        end

        % Button pushed function: FIRButton_2
        function FIRButton_2Pushed(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Filtering your data, Please wait.')
            
            % passing values between callbacks
            app.data_for_filtering=app.data_for_filtering;
            z=app.data_for_filtering; % z= reduced data for filtering
            app.cur_Folder = pwd;
            currentFolder=app.cur_Folder;
            
            % visualization: grey lamps (initialize)
            app.Lamp_16.Color=[0.65 0.65 0.65];
            app.Lamp_17.Color=[0.65 0.65 0.65];
            app.Lamp_12.Color=[0.65 0.65 0.65];
            app.Lamp_13.Color=[0.65 0.65 0.65];
            app.Lamp_14.Color=[0.65 0.65 0.65];
            app.Lamp_15.Color=[0.65 0.65 0.65];
            
            warning off
            % function: FIR filtering
            [Vij_FIR,~,N1,b1] = FIR(z,currentFolder); % (~ =test = figure of psd)
            
            
            if isempty(Vij_FIR)==0
                % Save and pass values between callbacks
                app.filt_data=Vij_FIR;
                app.N=N1;
                app.b=b1;
                
                % visualization in panel: PSD plot
                app.UIAxes3_2.Visible='on';
                % save the figure to workspace and set the desired resolution:
                test= frame2im(getframe(gcf));
                Ioo=imresize(test,[1024,1024]);
                % Display image
                imshow(Ioo, 'Parent', app.UIAxes3_2, ...
                    'XData', [1 app.UIAxes3_2.Position(3)], ...
                    'YData', [1 app.UIAxes3_2.Position(4)]);
                
                % visible display panel
                app.FIR.Visible='on';
                app.IIR.Visible='off';
                app.WaveletPanel.Visible='off';
                
                % visualisation
                app.FIRButton_2.BackgroundColor=[0.18,0.13,0.66]; % blue
                app.FIRButton_2.FontColor=[1,1,1]; % white
                % other 2 options
                app.IIRButton_2.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.IIRButton_2.FontColor=[0,0,0]; % black
                app.WaveletButton_2.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.WaveletButton_2.FontColor=[0,0,0]; % black
                
                % visualisation: green lamps
                app.Lamp_16.Color='green';
                app.Lamp_17.Color='green';
                clc
            end
        end

        % Button pushed function: PSDButton
        function PSDButtonPushed(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Please wait, the figures are created.')
            
            % passing values between call backs functions
            app.data_for_filtering=app.data_for_filtering; % user's unfiltered data
            z=app.data_for_filtering;
            app.cur_Folder=app.cur_Folder;
            currentFolder=app.cur_Folder;
            app.filt_data=app.filt_data; % reduced fitered data
            Vij_FIR=app.filt_data;
            app.N=app.N; % N-order of FIR filter
            N1=app.N;
            
            warning off
            % Function: Vij PSDs plots
            [k] = FIR_PSD_plots(z, Vij_FIR,N1,currentFolder);
            
            % visualisation: green lamp
            if (k==1)
                app.Lamp_13.Color='green';
            end
        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            clc
            % passing values between callbacks
            app.filt_data=app.filt_data;
            Vij_filtered=app.filt_data; % the filtered data
            app.cur_Folder=app.cur_Folder;
            currentFolder=app.cur_Folder;
            
            % function: computes statistics of filtered data
            [ ~ ] = stats_filtered_SGG_GRF( Vij_filtered,currentFolder);
            
            % visualisation: green lamp
            app.Lamp_14.Color='green';
        end

        % Button pushed function: FilteredReducedSGGButton
        function FilteredReducedSGGButtonPushed(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Please wait, the figures are created.')
            
            % passing values between call backs functions
            app.data_for_filtering=app.data_for_filtering; % user's unfiltered data
            z=app.data_for_filtering;
            app.cur_Folder=app.cur_Folder;
            currentFolder=app.cur_Folder;
            app.N=app.N; % N-order of FIR filter
            N1=app.N;
            app.filt_data=app.filt_data; % the reduced filtered data
            Vij_FIR=app.filt_data;
            
            % function: plot the SGG_filterd in GRF
            [ ~ ] = plot_filtered__sgg_grf_fir(Vij_FIR,z,N1,currentFolder);
            
            % visualisation: green lamp
            app.Lamp_12.Color='green';
            
        end

        % Button pushed function: MagnitudeResponseButton
        function MagnitudeResponseButtonPushed(app, event)
            clc
            % passing values of FIR between call backs functions
            app.b=app.b;
            b1=app.b;
            
            % Magnitude and Phase Response of Bandpass FIR Filter
            fvtool(b1,'Analysis','freq'); % matlab tool
            
            % visualisation: green lamp
            app.Lamp_15.Color='green';
        end

        % Button pushed function: IIRButton_2
        function IIRButton_2Pushed(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Filtering your data, Please wait.')
            
            % visualization: grey lamps (initialize)
            app.Lamp_18.Color=[0.65 0.65 0.65];
            app.Lamp_19.Color=[0.65 0.65 0.65];
            app.Lamp_20.Color=[0.65 0.65 0.65];
            app.Lamp_21.Color=[0.65 0.65 0.65];
            app.Lamp_22.Color=[0.65 0.65 0.65];
            app.Lamp_23.Color=[0.65 0.65 0.65];
            
            % passing values between callbacks
            app.data_for_filtering=app.data_for_filtering;
            z=app.data_for_filtering; % z= reduced data for filtering
            app.cur_Folder = pwd;
            currentFolder=app.cur_Folder;
            
            warning off
            % function: ΙIR filtering
            [Vij_IIR,~,N2,b2,a2] = IIR(z,currentFolder); % (~ =test = figure of psd)
            
            % Save and pass values between callbacks
            app.filt_data=Vij_IIR;
            app.N=N2;
            app.b=b2;
            app.a=a2;
            
            if isempty(Vij_IIR)==0
                % visualization in panel: PSD plot
                app.UIAxes3_3.Visible='on';
                %  save the figure to workspace and set the desired resolution:
                test= frame2im(getframe(gcf));
                Ioo=imresize(test,[1024,1024]);
                % Display image
                imshow(Ioo, 'Parent', app.UIAxes3_3, ...
                    'XData', [1 app.UIAxes3_3.Position(3)], ...
                    'YData', [1 app.UIAxes3_3.Position(4)]);
                
                % visible display panel
                app.IIR.Visible='on';
                app.WaveletPanel.Visible='off';
                app.FIR.Visible='off';
                % visualisation
                app.IIRButton_2.BackgroundColor=[0.18,0.13,0.66]; % blue
                app.IIRButton_2.FontColor=[1,1,1]; % white
                % other 2 options
                app.FIRButton_2.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.FIRButton_2.FontColor=[0,0,0]; % black
                app.WaveletButton_2.BackgroundColor=[0.96,0.96,0.96]; % grey
                app.WaveletButton_2.FontColor=[0,0,0]; % black
                % visualisation: green lamps
                app.Lamp_22.Color='green';
                app.Lamp_23.Color='green';
            end
            clc
        end

        % Callback function
        function StatisticsButton_2Pushed(app, event)
            clc
            % passing values between callbacks
            app.filt_data=app.filt_data;
            Vij_filtered=app.filt_data;
            app.cur_Folder=app.cur_Folder;
            currentFolder=app.cur_Folder;
            
            % function: compute the statistics of filtered data
            [ ~ ] = stats_filtered_SGG_GRF( Vij_filtered,currentFolder);
            
            % visualisation: green lamp
            app.Lamp_20.Color='green';
        end

        % Button pushed function: WaveletButton_2
        function WaveletButton_2Pushed(app, event)
            clc
            app.WaveletPanel.Visible='on';
            app.FIR.Visible='off';
            app.IIR.Visible='off';
            
            % visualization
            app.WaveletButton_2.BackgroundColor=[0.18,0.13,0.66]; % blue
            app.WaveletButton_2.FontColor=[1,1,1]; % white
            % other 2 options
            app.FIRButton_2.BackgroundColor=[0.96,0.96,0.96]; % grey
            app.FIRButton_2.FontColor=[0,0,0]; % black
            app.IIRButton_2.BackgroundColor=[0.96,0.96,0.96]; % grey
            app.IIRButton_2.FontColor=[0,0,0]; % black
        end

        % Button pushed function: FilteredReducedSGGButton_2
        function FilteredReducedSGGButton_2Pushed2(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Please wait, the figures are created.')
            
            % passing values between call backs functions
            app.data_for_filtering=app.data_for_filtering; % user's unfiltered data
            z=app.data_for_filtering;
            app.cur_Folder=app.cur_Folder;
            currentFolder=app.cur_Folder;
            app.N=app.N; % N-order IIR filter
            N2=app.N;
            app.filt_data=app.filt_data; % reduced fitered data
            Vij_IIR=app.filt_data;
            
            % function: plot the SGG_filterd in GRF
            [ ~ ] = plot_filtered__sgg_grf_iir(Vij_IIR,z,N2,currentFolder);
            
            % visualisation: green lamp
            app.Lamp_18.Color='green';
        end

        % Button pushed function: PSDButton_2
        function PSDButton_2Pushed2(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Please wait, the figures are created.')
            
            % passing values between callbacks functions
            app.data_for_filtering=app.data_for_filtering; % user's unfiltered data
            z=app.data_for_filtering;
            app.cur_Folder=app.cur_Folder;
            currentFolder=app.cur_Folder;
            app.filt_data=app.filt_data; % reduced filtered data
            Vij_IIR=app.filt_data;
            app.N=app.N; % N-order f IIR filter
            N2=app.N;
            warning off % warnings
            
            % function: plot PSDs
            [k] = IIR_PSD_plots(z, Vij_IIR,N2,currentFolder);
            
            % visualisation: green lamp
            if (k==1)
                app.Lamp_19.Color='green';
            end
        end

        % Button pushed function: MagnitudeResponseButton_2
        function MagnitudeResponseButton_2Pushed2(app, event)
            clc
            % passing values between callbacks functions
            app.b=app.b;
            b2=app.b;
            a2=app.a;
            
            % Magnitude and Phase Response of Bandpass IIR Filter
            fvtool(b2,a2); % matlab tool
            
            % visualisation: green lamp
            app.Lamp_21.Color='green';
        end

        % Button pushed function: StatisticsButton_2
        function StatisticsButton_2Pushed2(app, event)
            clc
            % passing values between callbacks functions
            app.filt_data=app.filt_data; % filtered data
            Vij_filtered=app.filt_data;
            app.cur_Folder=app.cur_Folder;
            currentFolder=app.cur_Folder;
            
            % function: compute statistics of filtered data
            [ ~ ] = stats_filtered_IIR_SGG_GRF( Vij_filtered,currentFolder);
            
            % visualisation: green lamp
            app.Lamp_20.Color='green';
        end

        % Button pushed function: WLMRAdecompositionButton
        function WLMRAdecompositionButtonPushed(app, event)
            clc
            % message/ command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Decomposing your data, Please wait.')
            
            %message for the user (please wait for the processing)
            % please wait message
            f = waitbar(0,'Please wait...');
            pause(2)
            waitbar(.50,f,'Processing your data');
            pause(2)
            waitbar(1,f,'Please wait...');
            pause(2)
            close(f)
            
            % initialize
            app.Lamp_GG_WL_MRA.Color=[0.65 0.65 0.65]; % grey
            app.Lampexportreport_GG_WL_MRA.Color=[0.65 0.65 0.65];
            app.Lamp_stats_WL_MRA.Color=[0.65 0.65 0.65];
            app.Lamp_GG_WL_MRA_plot.Color=[0.65 0.65 0.65];
            app.Lamp_psd_GG_WL_MRA_plot.Color=[0.65 0.65 0.65];
            app.Lamp_GG_WL_MRA_2.Color=[0.65 0.65 0.65];
            app.Lamp_GG_WL_MRA_3.Color=[0.65 0.65 0.65];
            
            % call data for filtering
            app.data_for_filtering=app.data_for_filtering;
            
            % clear axes
            cla(app.UIAxes_WL);
            cla(app.UIAxes_WL_2);
            
            data_for_filtering1=app.data_for_filtering;
            app.datafordecomposition1=data_for_filtering1;
            
            datafordecomposition=data_for_filtering1;
            [ app.wave_decomposed  ] = wavelets_decomposition(datafordecomposition);
            
            warning off
            % message/ command window
            disp('Creating figures of decomposition, Please wait.')
            [ w ] = plot_EGG_WL_MRA_decomp( app.wave_decomposed);
            [ w ] = plot_psd_WL_MRA_decomp(app.wave_decomposed);
            
            r2=[53 227 128]/255; % green RGB
            r3=[111 54 226]/255; % blue RBG
            
            % Display the data in the tab
            % plot Vzz after WL MRA
            app.UIAxes_WL_2.Visible='on';
            length1storbit=app.wave_decomposed{1,1}{1,1}{1};
            Vzz_original = cell2mat(app.datafordecomposition1{3,1}(1));
            Vzz_original_1 =Vzz_original(1:length(length1storbit));
            time_utc_nom_fractional_new = app.wave_decomposed{9,1}{1,1};
            time_utc_nom_fractional_new_1 =time_utc_nom_fractional_new(1:length(length1storbit));
            plot(app.UIAxes_WL_2,time_utc_nom_fractional_new_1,Vzz_original_1,"Color",[0.6350 0.0780 0.1840]);
            hold(app.UIAxes_WL_2,'off');
            
            warning off
            % plot psd of Vzz after WL MRA
            app.UIAxes_WL.Visible='on';
            x1=[0.005 0.005];
            x2=[0.1 0.1];
            y1=[10^(-8) 10^(6)];
            [freq2,power2] = f_normalized_psd_for_display(Vzz_original_1,1);
            semilogy(app.UIAxes_WL,freq2(1:end/2),power2(1:end/2),"Color",[0.6350 0.0780 0.1840]);
            ax=app.UIAxes_WL;
            ax.XLim=[10^(-5) 10^(0)];
            ax.YLim=[10^(-8) 10^(2)];
            ax.XScale='log';
            ax.YScale='log';
            %legend(app.UIAxes_WL_2,'PSD of Reconstructed Vzz','PSD of Original Vzz','location','northeast','AutoUpdate','on');
            hold(app.UIAxes_WL,'off');
            
            app.d1CheckBox.Enable='on';
            app.d2CheckBox.Enable='on';
            app.d3CheckBox.Enable='on';
            app.d4CheckBox.Enable='on';
            app.d5CheckBox.Enable='on';
            app.d6CheckBox.Enable='on';
            app.d7CheckBox.Enable='on';
            app.d8CheckBox.Enable='on';
            app.d9CheckBox.Enable='on';
            app.d10CheckBox.Enable='on';
            app.d11CheckBox.Enable='on';
            app.d12CheckBox.Enable='on';
            app.a12CheckBox.Enable='on';
            app.WLMRAreconstructionButton.Enable='on';
            app.GG_WLmatLabel.Enable='on';
            app.GG_WL_ReportmatLabel.Enable='on';
            app.Lamp_GG_WL_MRA_2.Color='green';
            % keep user's path/ save the outputs
            app.cur_Folder = pwd; % Identify current folder
        end

        % Button pushed function: WLMRAreconstructionButton
        function WLMRAreconstructionButtonPushed(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Reconstructing your data, Please wait.')
            
            % passing values between callbacks functions
            app.wave_decomposed=app.wave_decomposed;
            app.data_for_filtering=app.data_for_filtering;
            data_for_filtering2=app.data_for_filtering;
            app.datafordecomposition2=data_for_filtering2;
            datafordecomposition_2=data_for_filtering2;
            app.counter_GG_WL_MRA=app.counter_GG_WL_MRA +1;
            
            % clear axes
            cla(app.UIAxes_WL);
            cla(app.UIAxes_WL_2);
            
            % checking of boxes
            l1=app.d1CheckBox.Value;
            l2=app.d2CheckBox.Value;
            l3=app.d3CheckBox.Value;
            l4=app.d4CheckBox.Value;
            l5=app.d5CheckBox.Value;
            l6=app.d6CheckBox.Value;
            l7=app.d7CheckBox.Value;
            l8=app.d8CheckBox.Value;
            l9=app.d9CheckBox.Value;
            l10=app.d10CheckBox.Value;
            l11=app.d11CheckBox.Value;
            l12=app.d12CheckBox.Value;
            l13=app.a12CheckBox.Value;
            listcheck=[l1; l2; l3; l4; l5; l6; l7; l8; l9; l10; l11; l12; l13];
            
            % function: wavelets reconstruction
            dataforreconstruction=app.wave_decomposed;
            [ app.wave_reconstructed  ] = wavelets_reconstruction(dataforreconstruction, listcheck);
            
            % function: wavelets_days
            dataorbits=app.wave_reconstructed;
            [ app.GG_WL ]=wavelets_days(dataorbits,datafordecomposition_2);
            
            % visualisation: green lamp
            app.Lamp_GG_WL_MRA.Color= 'Green';
            app.Lampexportreport_GG_WL_MRA.Color= 'Green';
            
            % Display the data in the tab:
            % plot Vzz after WL MRA
            app.UIAxes_WL_2.Visible='on';
            Vzz = cell2mat(app.wave_reconstructed{3,1}(1));
            Vzz_original = cell2mat(app.datafordecomposition1{3,1}(1));
            Vzz_original_1 =Vzz_original(1:length(Vzz));
            time_utc_nom_fractional_new = app.GG_WL{4,1}{1,1};
            time_utc_nom_fractional_new_1 =time_utc_nom_fractional_new(1:length(Vzz));
            plot(app.UIAxes_WL_2,time_utc_nom_fractional_new_1,Vzz,"Color",[0.62 0.93 0.71]);
            hold(app.UIAxes_WL_2,'on');
            plot(app.UIAxes_WL_2,time_utc_nom_fractional_new_1,Vzz_original_1,"Color",[0.6350 0.0780 0.1840]);
            hold(app.UIAxes_WL_2,'off');
            
            warning off
            % plot psd of Vzz after WL MRA
            app.UIAxes_WL.Visible='on';
            [freq,power] = f_normalized_psd_for_display(Vzz,1);
            [freq2,power2] = f_normalized_psd_for_display(Vzz_original_1,1);
            semilogy(app.UIAxes_WL,freq(1:end/2),power(1:end/2),"Color",[0.62 0.93 0.71]);
            hold(app.UIAxes_WL,'on');
            semilogy(app.UIAxes_WL,freq2(1:end/2),power2(1:end/2),"Color",[0.6350 0.0780 0.1840]);
            ax=app.UIAxes_WL;
            ax.XLim=[10^(-5) 10^(0)];
            ax.YLim=[10^(-8) 10^(2)];
            ax.XScale='log';
            ax.YScale='log';
            %legend(app.UIAxes_WL,'PSD of Reconstructed Vzz','PSD of Original Vzz','location','northeast','AutoUpdate','on');
            hold(app.UIAxes_WL,'off');
            
            % enable buttons
            app.statisticsGGWLmatReporttxtButton.Enable='on';
            app.GGsafterWLMRAButton.Enable='on';
            app.PSDsofGGsafterWLMRAButton.Enable='on';
            % visualisation: green lamp
            app.Lamp_GG_WL_MRA_3.Color='green';
        end

        % Button pushed function: statisticsGGWLmatReporttxtButton
        function statisticsGGWLmatReporttxtButtonPushed(app, event)
            clc
            % passing values between callbacks functions
            app.counter_stats_GG_WL_MRA_Vij=app.counter_stats_GG_WL_MRA_Vij+1;
            app.cur_Folder=app.cur_Folder; % path
            currentFolder=app.cur_Folder;
            
            % function: calculate the stats
            [ ~ ] =stats_GG_WL_MRA( app.GG_WL,currentFolder);
            
            % visualisation: green lamp
            app.Lamp_stats_WL_MRA.Color= 'Green';
        end

        % Button pushed function: GGsafterWLMRAButton
        function GGsafterWLMRAButtonPushed(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Please wait, the figures are created.')
            
            % passing values between callbacks functions
            app.counter_GG_WL_MRA_plot=app.counter_GG_WL_MRA_plot+1;
            app.GG_WL=app.GG_WL;
            
            % function: plot GGs after WL MRA
            [ ~ ] =  plot_EGG_WL_MRA(app.GG_WL);
            
            % visualisation: green lamp
            app.Lamp_GG_WL_MRA_plot.Color= 'Green';
        end

        % Button pushed function: PSDsofGGsafterWLMRAButton
        function PSDsofGGsafterWLMRAButtonPushed(app, event)
            clc
            % message for the user in the command window
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Please wait, the figures are created.')
            
            % passing values between callbacks functions
            app.counter_psd_GG_WL_MRA_plot=app.counter_psd_GG_WL_MRA_plot+1;
            app.GG_WL=app.GG_WL;
            app.data_for_filtering=app.data_for_filtering;
            
            % function: plot PSDs of GGs after WL MRA
            [ ~ ] = plot_psd_WL_MRA(app.GG_WL,app.data_for_filtering);
            
            % visualisation: green lamp
            app.Lamp_psd_GG_WL_MRA_plot.Color= 'Green';
            clc
        end

        % Button pushed function: 
        % LoaddataforGRFtoLNOFtransformationButton
        function LoaddataforGRFtoLNOFtransformationButtonPushed(app, event)
            clc
            % passing values between callbacks functions
            app.GG_GRF_data=app.GG_GRF_data;
            
            % function: Load data for GRF to LNOF transformation
            [app.GG_GRF_data,l] = load_GGs_GRF();
            
            % visualisation: grey lamp
            app.LampdataGRFtoLNOF.Color= [0.65 0.65 0.65];
            
            % message for user
            if (l==0)
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Please, reload the  data.'}, 'Load data in GRF', 'help', Opt);
            elseif (l==1)
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Data in GRF were successfully loaded.'}, 'Load data in GRF', 'help', Opt);
            end
            
            % Checking if data were loaded
            if isempty(app.GG_GRF_data)==0
                app.counter_GG_GRF=app.counter_GG_GRF +1;
                app.LampdataGRFtoLNOF.Color= 'Green';
            end
            
            % enable pushbuttons
            app.TransformationGRFtoLNOFButton.Enable='on';
            app.StatisticsofthedatainLNOFmatReportButton.Enable='off';
            app.FiguresofthedatainLNOFButton.Enable='off';
            
            % visualisation: grey lamps
            app.LamptransformationGRFtoLNOF.Color= [0.65 0.65 0.65];
            app.LampfigtransfLNOF.Color= [0.65 0.65 0.65];
            app.LampstatstransfLNOF.Color=[0.65 0.65 0.65];
            app.UIAxes_Vzz_LNOF_4.Visible='off';
            
            % clear axes
            cla(app.UIAxes_Vzz_LNOF_4);
            cla(app.UIAxes_Vzz_GRF_4);
            
            % enable pushbuttons
            if (l==0)
                app.TransformationGRFtoLNOFButton.Enable='off';
            end
        end

        % Button pushed function: TransformationGRFtoLNOFButton
        function TransformationGRFtoLNOFButtonPushed(app, event)
            % visualisation: grey lamp
            app.LamptransformationGRFtoLNOF.Color= [0.65 0.65 0.65];
            % passing values between callbacks functions
            app.GG_GRF_data=app.GG_GRF_data;
            
            % message for the user
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Processing your data, Please wait.')
            f = waitbar(0,'Please wait...');
            pause(2)
            waitbar(.50,f,'Processing your data'); %  na mpei meta to loading
            pause(2)
            waitbar(1,f,'Please wait...');
            pause(2)
            close(f)
            
            % functions: Transformations
            datagrftolnof1=app.GG_GRF_data;
            [app.VIRFgradients] = gradients_to_irf(datagrftolnof1);
            datagrftolnof2=app.VIRFgradients;
            [app.VEFRFgradients] = gradients_to_efrf(datagrftolnof2);
            datagrftolnof3=app.VEFRFgradients;
            [app.VLNOF_gradients] = gradients_to_lnof(datagrftolnof3);
            
            %display the data
            % plot Vzz-GRF
            r3=[111 54 226]/255; % blue RBG
            r4=[222 58 128]/255; % pink RBG
            app.UIAxes_Vzz_GRF_3.Visible='on';
            Vzz = cell2mat(app.GG_GRF_data{8,1}(1));
            time_utc_nom_fractional_new = app.GG_GRF_data{4,1}(1);
            plot(app.UIAxes_Vzz_GRF_3,time_utc_nom_fractional_new{1},Vzz,"Color",r4);
            
            app.UIAxes_Vzz_LNOF_3.Visible='on';
            Vzz = cell2mat(app.VLNOF_gradients{8,1}(1));
            time_utc_nom_fractional_new = app.VLNOF_gradients{4,1}(1);
            plot(app.UIAxes_Vzz_LNOF_3,time_utc_nom_fractional_new{1},Vzz,"Color",r3);
            
            app.LamptransformationGRFtoLNOF.Color= 'Green';
            app.StatisticsofthedatainLNOFmatReportButton.Enable='on';
            app.FiguresofthedatainLNOFButton.Enable='on';
            
            app.cur_Folder = pwd; % Identify current folder
        end

        % Button pushed function: 
        % StatisticsofthedatainLNOFmatReportButton
        function StatisticsofthedatainLNOFmatReportButtonPushed(app, event)
            clc
            % passing values between callbacks functions
            app.counter_stats_GGs_2_LNOF=app.counter_stats_GGs_2_LNOF+1;
            app.cur_Folder=app.cur_Folder;
            currentFolder=app.cur_Folder;
            app.VLNOF_gradients=app.VLNOF_gradients;
            
            % function: compute stats
            [ ~ ] =stats_GGs_2_LNOF( app.VLNOF_gradients,currentFolder);
            
            % visualisation: green lamp
            app.LampstatstransfLNOF.Color= 'Green';
        end

        % Button pushed function: FiguresofthedatainLNOFButton
        function FiguresofthedatainLNOFButtonPushed(app, event)
            clc
            
            % message for the user
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Creating figures, Please wait.')
            f = waitbar(0,'Please wait...');
            pause(2)
            waitbar(.50,f,'Creating figures'); %  na mpei meta to loading
            pause(2)
            waitbar(1,f,'Please wait...');
            pause(2)
            close(f)
            
            % visualisation: grey lamp
            app.LampfigtransfLNOF.Color= [0.65 0.65 0.65];
            
            % passing values between callbacks functions
            app.counter_GG_LNOF=app.counter_GG_LNOF +1;
            app.VLNOF_gradients=app.VLNOF_gradients;
            
            % function: creates Figures of the data in LNOF
            [ ~ ] = plot_GG_LNOF( app.VLNOF_gradients);
            
            % visualisation: green lamp
            app.LampfigtransfLNOF.Color= 'Green';
        end

        % Button pushed function: 
        % LoaddataforLNOFtoGRFtransformationButton
        function LoaddataforLNOFtoGRFtransformationButtonPushed(app, event)
            clc
            % visualisation: grey lamp
            app.LampdataLNOFtoGRF.Color= [0.65 0.65 0.65];
            
            % passing values between callbacks functions
            app.GG_LNOF_data=app.GG_LNOF_data;
            
            % function: Load data for LNOF to GRF transformation
            [app.GG_LNOF_data,m] = load_GGs_LNOF();
            
            % message for the user
            if (m==0)
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Please, reload the  data.'}, 'Load data in LNOF', 'help', Opt);
                app.TransformationLNOFtoGRFButton.Enable='off';
            elseif (m==1)
                Opt.Interpreter = 'tex';
                Opt.WindowStyle = 'normal';
                msgbox({'Data in LNOF were successfully loaded.'}, 'Load data in LNOF', 'help', Opt);
            end
            
            % Checking if data were loaded
            if isempty(app.GG_LNOF_data)==0
                app.counter_GG_LNOF_2=app.counter_GG_LNOF_2 +1;
                app.LampdataLNOFtoGRF.Color= 'Green';
            end
            
            % enable pushbuttons
            app.TransformationLNOFtoGRFButton.Enable='on';
            app.StatisticsofthedatainGRFmatReportButton.Enable='off';
            app.FiguresofthedatainGRFButton.Enable='off';
            
            % visualisation: grey lamps
            app.LamptransformationLNOFtoGRF.Color= [0.65 0.65 0.65];
            app.LampstatstransfGRF.Color= [0.65 0.65 0.65];
            app.LampFIGtransfGRF.Color= [0.65 0.65 0.65];
            app.UIAxes_Vzz_LNOF_4.Visible='off';
            app.UIAxes_Vzz_GRF_4.Visible='off';
            
            % clear axes
            cla(app.UIAxes_Vzz_LNOF_4);
            cla(app.UIAxes_Vzz_GRF_4);
            
            % enable pushbutton
            if (m==0)
                app.TransformationLNOFtoGRFButton.Enable='off';
            end
        end

        % Button pushed function: TransformationLNOFtoGRFButton
        function TransformationLNOFtoGRFButtonPushed(app, event)
            % passing values between callbacks functions
            app.GG_LNOF_data=app.GG_LNOF_data;
            app.VGRF_gradients=app.VGRF_gradients;
            datalnoftogrf=app.GG_LNOF_data;
            
            % message for the user
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Processing your data, Please wait.')
            f = waitbar(0,'Please wait...');
            pause(2)
            waitbar(.50,f,'Processing your data'); %  na mpei meta to loading
            pause(2)
            waitbar(1,f,'Please wait...');
            pause(2)
            close(f)
            
            % function: Transformation LNOF to GRF
            [ app.VGRF_gradients ] = GGs_Transformation_LNOF_2_GRF(datalnoftogrf);
            
            %display the data
            % plot Vzz-GRF
            r3=[111 54 226]/255; % blue RBG
            r4=[222 58 128]/255; % pink RBG
            app.UIAxes_Vzz_LNOF_4.Visible='on';
            Vzz = cell2mat(app.GG_LNOF_data{8,1}(1));
            time_utc_nom_fractional_new = app.GG_LNOF_data{4,1}(1);
            plot(app.UIAxes_Vzz_LNOF_4,time_utc_nom_fractional_new{1},Vzz,"Color",r3);
            
            app.UIAxes_Vzz_GRF_4.Visible='on';
            Vzz = cell2mat(app.VGRF_gradients{8,1}(1));
            time_utc_nom_fractional_new = app.GG_LNOF_data{4,1}(1);
            plot(app.UIAxes_Vzz_GRF_4,time_utc_nom_fractional_new{1},Vzz,"Color",r4);
            
            % visualisation: green lamp
            app.LamptransformationLNOFtoGRF.Color= 'Green';
            % enale pushbuttons
            app.StatisticsofthedatainGRFmatReportButton.Enable='on';
            app.FiguresofthedatainGRFButton.Enable='on';
        end

        % Button pushed function: 
        % StatisticsofthedatainGRFmatReportButton
        function StatisticsofthedatainGRFmatReportButtonPushed(app, event)
            clc
            % passing values between callbacks functions
            app.counter_stats_GGs_2_GRF=app.counter_stats_GGs_2_GRF+1;
            app.cur_Folder=app.cur_Folder;
            currentFolder=app.cur_Folder;
            app.VGRF_gradients=app.VGRF_gradients;
            
            % function: Statistics of the data in GR
            [ ~ ] =stats_GGs_2_GRF( app.VGRF_gradients,currentFolder);
            
            % visualisation: green lamp
            app.LampstatstransfGRF.Color= 'Green';
        end

        % Button pushed function: FiguresofthedatainGRFButton
        function FiguresofthedatainGRFButtonPushed(app, event)
            clc
            % passing values between callbacks functions
            app.counter_GG_GRF=app.counter_GG_GRF +1;
            app.VGRF_gradients=app.VGRF_gradients;
            
            % message for the user
            fprintf(2, '\nGeoGravGOCE Info:\n')
            disp('Creating figures, Please wait.')
            f = waitbar(0,'Please wait...');
            pause(2)
            waitbar(.50,f,'Creating figures');
            pause(2)
            waitbar(1,f,'Please wait...');
            pause(2)
            close(f)
            
            % function: figures of the data in GRF
            [ ~ ] = plot_GG_GRF( app.VGRF_gradients);
            
            % visualisation: green lamp
            app.LampFIGtransfGRF.Color= 'Green';
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            % SGG Pre-processing tab
            
            % clear/ reset all the variables
            delete(app.GeoGravGOCEFigure);
            gui_name = 'geogravgoce';
            feval(gui_name);
            clc
        end

        % Button pushed function: clearButton_2
        function clearButton_2Pushed(app, event)
            % Filtering tab
            
            % clear/ reset all the variables
            delete(app.GeoGravGOCEFigure);
            gui_name = 'geogravgoce';
            feval(gui_name);
            clc
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeoGravGOCEFigure = uifigure('Visible', 'off');
            app.GeoGravGOCEFigure.Name = 'GeoGravGOCE';
            app.TabGroup = uitabgroup(app.GeoGravGOCEFigure);
            app.SGGPreprocessingTab = uitab(app.TabGroup);
            app.SGGPreprocessingTab.Title = 'SGG Pre-processing';
            app.DisplayPanel_2 = uipanel(app.SGGPreprocessingTab);
            app.DisplayPanel_2.Title = '- Display -';
            app.UIAxes = uiaxes(app.DisplayPanel_2);
            title(app.UIAxes, 'Gravity Gradient - Vzz - GRF')
            xlabel(app.UIAxes, 'UTC (time)')
            ylabel(app.UIAxes, 'Eötvös')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.DisplayPanel_2);
            title(app.UIAxes_3, 'GOCE Altitude')
            xlabel(app.UIAxes_3, 'UTC (time)')
            ylabel(app.UIAxes_3, 'Altitude (m)')
            app.UIAxes_3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.DisplayPanel_2);
            title(app.UIAxes_4, 'Gravity Gradient - Vyy - GRF')
            xlabel(app.UIAxes_4, 'UTC (time)')
            ylabel(app.UIAxes_4, 'Eötvös')
            app.UIAxes_4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_4.Visible = 'off';
            app.GrafLabPanel = uipanel(app.SGGPreprocessingTab);
            app.GrafLabPanel.Title = '- GrafLab -';
            app.CreateInputFormatlatlonhButton = uibutton(app.GrafLabPanel, 'push');
            app.CreateInputFormatlatlonhButton.ButtonPushedFcn = createCallbackFcn(app, @CreateInputFormatlatlonhButtonPushed, true);
            app.CreateInputFormatlatlonhButton.Enable = 'off';
            app.CreateInputFormatlatlonhButton.Text = 'Create Input Format: (lat,lon,h)';
            app.RUNGrafLabButton = uibutton(app.GrafLabPanel, 'push');
            app.RUNGrafLabButton.ButtonPushedFcn = createCallbackFcn(app, @RUNGrafLabButtonPushed, true);
            app.RUNGrafLabButton.Enable = 'off';
            app.RUNGrafLabButton.Text = 'RUN GrafLab';
            app.Lamp_6 = uilamp(app.GrafLabPanel);
            app.Lamp_7 = uilamp(app.GrafLabPanel);
            app.Label = uilabel(app.GrafLabPanel);
            app.Label.Text = '';
            app.SGGPanel = uipanel(app.SGGPreprocessingTab);
            app.SGGPanel.Title = '- SGG -';
            app.ExportdataPanel = uipanel(app.SGGPanel);
            app.ExportdataPanel.Title = '  Export data:';
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel = uilabel(app.ExportdataPanel);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel.Text = {'"SGG_GRF.mat"  &  "SGG_GRF_Report"'; 'will be saved in your working folder automatically.'};
            app.SGG_GRFmatLabel = uilabel(app.ExportdataPanel);
            app.SGG_GRFmatLabel.Enable = 'off';
            app.SGG_GRFmatLabel.Text = ' SGG_GRF (.mat)';
            app.SGG_GRF_ReporttxtLabel = uilabel(app.ExportdataPanel);
            app.SGG_GRF_ReporttxtLabel.Enable = 'off';
            app.SGG_GRF_ReporttxtLabel.Text = ' SGG_GRF_Report (.txt)';
            app.Lamp_4 = uilamp(app.ExportdataPanel);
            app.Lamp_5 = uilamp(app.ExportdataPanel);
            app.StatisticsVijmatReporttxtButton = uibutton(app.ExportdataPanel, 'push');
            app.StatisticsVijmatReporttxtButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsVijmatReporttxtButtonPushed, true);
            app.StatisticsVijmatReporttxtButton.Enable = 'off';
            app.StatisticsVijmatReporttxtButton.Text = 'Statistics Vij (.mat)  & Report (.txt)';
            app.Lamp_8 = uilamp(app.ExportdataPanel);
            app.GravityGradientsButton = uibutton(app.ExportdataPanel, 'push');
            app.GravityGradientsButton.ButtonPushedFcn = createCallbackFcn(app, @GravityGradientsButtonPushed, true);
            app.GravityGradientsButton.Enable = 'off';
            app.GravityGradientsButton.Text = 'Gravity Gradients ';
            app.Lamp_9 = uilamp(app.ExportdataPanel);
            app.GOCEOrbitTrackButton = uibutton(app.ExportdataPanel, 'push');
            app.GOCEOrbitTrackButton.ButtonPushedFcn = createCallbackFcn(app, @GOCEOrbitTrackButtonPushed, true);
            app.GOCEOrbitTrackButton.Enable = 'off';
            app.GOCEOrbitTrackButton.Text = 'GOCE Orbit Track ';
            app.Lamp_10 = uilamp(app.ExportdataPanel);
            app.GOCEAltitudeButton = uibutton(app.ExportdataPanel, 'push');
            app.GOCEAltitudeButton.ButtonPushedFcn = createCallbackFcn(app, @GOCEAltitudeButtonPushed, true);
            app.GOCEAltitudeButton.Enable = 'off';
            app.GOCEAltitudeButton.Text = 'GOCE Altitude ';
            app.Lamp_11 = uilamp(app.ExportdataPanel);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_7 = uilabel(app.ExportdataPanel);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_7.Text = 'Export Statistics & Figures (.jpeg, .fig):';
            app.LoadDataafterParsingPanel = uipanel(app.SGGPanel);
            app.LoadDataafterParsingPanel.Title = '  Load Data (after Parsing):';
            app.EGG_NOM_2Button = uibutton(app.LoadDataafterParsingPanel, 'push');
            app.EGG_NOM_2Button.ButtonPushedFcn = createCallbackFcn(app, @EGG_NOM_2ButtonPushed, true);
            app.EGG_NOM_2Button.Text = 'EGG_NOM_2';
            app.SST_PSO_2Button = uibutton(app.LoadDataafterParsingPanel, 'push');
            app.SST_PSO_2Button.ButtonPushedFcn = createCallbackFcn(app, @SST_PSO_2ButtonPushed, true);
            app.SST_PSO_2Button.Text = 'SST_PSO_2 ';
            app.CalculateSGGButton = uibutton(app.LoadDataafterParsingPanel, 'push');
            app.CalculateSGGButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSGGButtonPushed, true);
            app.CalculateSGGButton.Enable = 'off';
            app.CalculateSGGButton.Text = 'Calculate SGG';
            app.Lamp = uilamp(app.LoadDataafterParsingPanel);
            app.Lamp_2 = uilamp(app.LoadDataafterParsingPanel);
            app.Lamp_3 = uilamp(app.LoadDataafterParsingPanel);
            app.clearButton = uibutton(app.SGGPreprocessingTab, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.GGMtransformationLNOFtoGRFTab = uitab(app.TabGroup);
            app.GGMtransformationLNOFtoGRFTab.Title = 'GGM transformation: LNOF to GRF';
            app.LoadDataforTransformationPanel = uipanel(app.GGMtransformationLNOFtoGRFTab);
            app.LoadDataforTransformationPanel.Title = ' - Load Data for Transformation -';
            app.TransformLNOFtoGRFButton = uibutton(app.LoadDataforTransformationPanel, 'push');
            app.TransformLNOFtoGRFButton.ButtonPushedFcn = createCallbackFcn(app, @TransformLNOFtoGRFButtonPushed, true);
            app.TransformLNOFtoGRFButton.Enable = 'off';
            app.TransformLNOFtoGRFButton.Text = 'Transform LNOF to GRF';
            app.GGMfileButton = uibutton(app.LoadDataforTransformationPanel, 'push');
            app.GGMfileButton.ButtonPushedFcn = createCallbackFcn(app, @GGMfileButtonPushed, true);
            app.GGMfileButton.Enable = 'off';
            app.GGMfileButton.Text = 'GGM file ';
            app.LampGGM = uilamp(app.LoadDataforTransformationPanel);
            app.QuaternionsPSO_2_QATButton = uibutton(app.LoadDataforTransformationPanel, 'push');
            app.QuaternionsPSO_2_QATButton.ButtonPushedFcn = createCallbackFcn(app, @QuaternionsPSO_2_QATButtonPushed, true);
            app.QuaternionsPSO_2_QATButton.Enable = 'off';
            app.QuaternionsPSO_2_QATButton.Text = 'Quaternions (PSO_2_QAT)';
            app.LampQAT = uilamp(app.LoadDataforTransformationPanel);
            app.LoadthecorrespondingwiththefirsttabPSOQuaternionsFileLabel = uilabel(app.LoadDataforTransformationPanel);
            app.LoadthecorrespondingwiththefirsttabPSOQuaternionsFileLabel.Text = {'Load the corresponding with the first tab PSO'; 'Quaternion''s File'};
            app.LamptransformLNOF2GRF = uilamp(app.LoadDataforTransformationPanel);
            app.ExporttransformeddatainGRFPanel = uipanel(app.GGMtransformationLNOFtoGRFTab);
            app.ExporttransformeddatainGRFPanel.Title = ' - Export transformed data in GRF -';
            app.LampexportGGMGRF = uilamp(app.ExporttransformeddatainGRFPanel);
            app.LampexportreportGGMGRF = uilamp(app.ExporttransformeddatainGRFPanel);
            app.GGM_LNOF_2_GRFmatLabel = uilabel(app.ExporttransformeddatainGRFPanel);
            app.GGM_LNOF_2_GRFmatLabel.Text = 'GGM_LNOF_2_GRF (.mat)';
            app.GGM_LNOF_2_GRF_ReportLabel = uilabel(app.ExporttransformeddatainGRFPanel);
            app.GGM_LNOF_2_GRF_ReportLabel.Text = 'GGM_LNOF_2_GRF_Report ';
            app.ThesefileswillbesavedinyourworkingfolderautomaticallyLabel_2 = uilabel(app.ExporttransformeddatainGRFPanel);
            app.ThesefileswillbesavedinyourworkingfolderautomaticallyLabel_2.Text = {'"GGM_LNOF_2_GRF.mat"  &  "GGM_LNOF_2_GRF_Report"'; 'will be saved in your working folder automatically.'};
            app.StatisticsGGMVijinGRFmatReportButton = uibutton(app.ExporttransformeddatainGRFPanel, 'push');
            app.StatisticsGGMVijinGRFmatReportButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsGGMVijinGRFmatReportButtonPushed, true);
            app.StatisticsGGMVijinGRFmatReportButton.Enable = 'off';
            app.StatisticsGGMVijinGRFmatReportButton.Text = 'Statistics GGM Vij in GRF(.mat) & Report';
            app.LampexportGGMGRFstats = uilamp(app.ExporttransformeddatainGRFPanel);
            app.FiguresofVijinGRFButton = uibutton(app.ExporttransformeddatainGRFPanel, 'push');
            app.FiguresofVijinGRFButton.ButtonPushedFcn = createCallbackFcn(app, @FiguresofVijinGRFButtonPushed, true);
            app.FiguresofVijinGRFButton.Enable = 'off';
            app.FiguresofVijinGRFButton.Text = 'Figures of Vij in GRF ';
            app.LampexportGGMGRFjpeg = uilamp(app.ExporttransformeddatainGRFPanel);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_8 = uilabel(app.ExporttransformeddatainGRFPanel);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_8.Text = 'Export Statistics & Figures (.jpeg, .fig):';
            app.DisplayPanel = uipanel(app.GGMtransformationLNOFtoGRFTab);
            app.DisplayPanel.Title = '- Display -';
            app.UIAxes_Vzz_LNOF = uiaxes(app.DisplayPanel);
            title(app.UIAxes_Vzz_LNOF, 'Gravity Gradient - Vzz - LNOF')
            ylabel(app.UIAxes_Vzz_LNOF, 'Eötvös')
            app.UIAxes_Vzz_LNOF.Visible = 'off';
            app.UIAxes_Vzz_EFRF = uiaxes(app.DisplayPanel);
            title(app.UIAxes_Vzz_EFRF, 'Gravity Gradient - Vzz - EFRF')
            xlabel(app.UIAxes_Vzz_EFRF, 'UTC (time)')
            ylabel(app.UIAxes_Vzz_EFRF, 'Eötvös')
            app.UIAxes_Vzz_EFRF.Visible = 'off';
            app.UIAxes_Vzz_IRF = uiaxes(app.DisplayPanel);
            title(app.UIAxes_Vzz_IRF, 'Gravity Gradient - Vzz - IRF')
            xlabel(app.UIAxes_Vzz_IRF, 'UTC (time)')
            ylabel(app.UIAxes_Vzz_IRF, 'Eötvös')
            app.UIAxes_Vzz_IRF.Visible = 'off';
            app.UIAxes_Vzz_GRF = uiaxes(app.DisplayPanel);
            title(app.UIAxes_Vzz_GRF, 'Gravity Gradient - Vzz - GRF')
            xlabel(app.UIAxes_Vzz_GRF, 'UTC (time)')
            ylabel(app.UIAxes_Vzz_GRF, 'Eötvös')
            app.UIAxes_Vzz_GRF.Visible = 'off';
            app.ImporttheGGMgravitationaltensorcomponentsGrafLabsoutputLabel_2 = uilabel(app.GGMtransformationLNOFtoGRFTab);
            app.ImporttheGGMgravitationaltensorcomponentsGrafLabsoutputLabel_2.Text = {'Import the GGM gravitational tensor components '; '(GrafLab output- lat,lon,h,Vxx,Vyy,Vzz,Vxy, Vxz,Vyz)'};
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.LoadReducedDataPanel = uipanel(app.FilteringTab);
            app.LoadReducedDataPanel.Title = '- Load Reduced Data -';
            app.ReducedDataButton = uibutton(app.LoadReducedDataPanel, 'push');
            app.ReducedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReducedDataButtonPushed, true);
            app.ReducedDataButton.Text = ' Reduced Data';
            app.SSG_GRFGGM_LNOF_2_GRFButton = uibutton(app.LoadReducedDataPanel, 'push');
            app.SSG_GRFGGM_LNOF_2_GRFButton.ButtonPushedFcn = createCallbackFcn(app, @SSG_GRFGGM_LNOF_2_GRFButtonPushed, true);
            app.SSG_GRFGGM_LNOF_2_GRFButton.Text = '[SSG_GRF]   &   [GGM_LNOF_2_GRF] ';
            app.SGG_GRF1sttabsoutputGGM_LNOF_2_GRF2sttabsoutputLabel = uilabel(app.LoadReducedDataPanel);
            app.SGG_GRF1sttabsoutputGGM_LNOF_2_GRF2sttabsoutputLabel.Text = {'Your reduced data. Pay attention to the required input format.'; ''};
            app.SGG_GRF1sttabsoutputGGM_LNOF_2_GRF2sttabsoutputLabel_2 = uilabel(app.LoadReducedDataPanel);
            app.SGG_GRF1sttabsoutputGGM_LNOF_2_GRF2sttabsoutputLabel_2.Text = {'SGG_GRF (1st tab''s output), GGM_LNOF_2_GRF (2nd tab''s output),'; 'in order to reduce and filter the data.'};
            app.SGG_GRF1sttabsoutputGGM_LNOF_2_GRF2sttabsoutputLabel_3 = uilabel(app.LoadReducedDataPanel);
            app.SGG_GRF1sttabsoutputGGM_LNOF_2_GRF2sttabsoutputLabel_3.Text = {'SGG_GRF (1st tab''s output) & your GGM gravitational tensor components.'; 'Pay attention to the required format. '};
            app.SSG_GRFGGMgravitationaltensorButton = uibutton(app.LoadReducedDataPanel, 'push');
            app.SSG_GRFGGMgravitationaltensorButton.ButtonPushedFcn = createCallbackFcn(app, @SSG_GRFGGMgravitationaltensorButtonPushed, true);
            app.SSG_GRFGGMgravitationaltensorButton.Text = '[SSG_GRF]   &   [GGM gravitational tensor] ';
            app.SelectFilteringMethodPanel = uipanel(app.FilteringTab);
            app.SelectFilteringMethodPanel.Title = '- Select Filtering Method -';
            app.IIRButton_2 = uibutton(app.SelectFilteringMethodPanel, 'push');
            app.IIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @IIRButton_2Pushed, true);
            app.IIRButton_2.Enable = 'off';
            app.IIRButton_2.Text = 'IIR';
            app.WaveletButton_2 = uibutton(app.SelectFilteringMethodPanel, 'push');
            app.WaveletButton_2.ButtonPushedFcn = createCallbackFcn(app, @WaveletButton_2Pushed, true);
            app.WaveletButton_2.Enable = 'off';
            app.WaveletButton_2.Text = 'Wavelet';
            app.FIRButton_2 = uibutton(app.SelectFilteringMethodPanel, 'push');
            app.FIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @FIRButton_2Pushed, true);
            app.FIRButton_2.Enable = 'off';
            app.FIRButton_2.Text = 'FIR';
            app.FIR = uipanel(app.SelectFilteringMethodPanel);
            app.FIR.Title = '- Display -';
            app.FIR.Visible = 'off';
            app.UIAxes3_2 = uiaxes(app.FIR);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, '')
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            app.UIAxes3_2.Visible = 'off';
            app.PSDButton = uibutton(app.FIR, 'push');
            app.PSDButton.ButtonPushedFcn = createCallbackFcn(app, @PSDButtonPushed, true);
            app.PSDButton.Text = 'PSD';
            app.FilteredReducedSGGButton = uibutton(app.FIR, 'push');
            app.FilteredReducedSGGButton.ButtonPushedFcn = createCallbackFcn(app, @FilteredReducedSGGButtonPushed, true);
            app.FilteredReducedSGGButton.Text = 'Filtered Reduced SGG';
            app.StatisticsButton = uibutton(app.FIR, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Text = 'Statistics';
            app.MagnitudeResponseButton = uibutton(app.FIR, 'push');
            app.MagnitudeResponseButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudeResponseButtonPushed, true);
            app.MagnitudeResponseButton.Text = 'Magnitude Response';
            app.DesignMethodFIRWindowHammingLabel = uilabel(app.FIR);
            app.DesignMethodFIRWindowHammingLabel.Text = 'Design Method: FIR / Window/ Hamming.';
            app.Lamp_12 = uilamp(app.FIR);
            app.Lamp_13 = uilamp(app.FIR);
            app.Lamp_14 = uilamp(app.FIR);
            app.Lamp_15 = uilamp(app.FIR);
            app.Vij_FIR_ReporttxtLabel = uilabel(app.FIR);
            app.Vij_FIR_ReporttxtLabel.Text = ' Vij_FIR_Report (.txt)';
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_2 = uilabel(app.FIR);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_2.Text = {'"Vij_FIR.mat"  &  "Vij_FIR_Report"'; 'were saved in your working folder'; 'automatically.'};
            app.Vij_FIRmatLabel = uilabel(app.FIR);
            app.Vij_FIRmatLabel.Text = ' Vij_FIR (.mat)';
            app.Lamp_16 = uilamp(app.FIR);
            app.Lamp_17 = uilamp(app.FIR);
            app.IIR = uipanel(app.SelectFilteringMethodPanel);
            app.IIR.Title = '- Display -';
            app.IIR.Visible = 'off';
            app.UIAxes3_3 = uiaxes(app.IIR);
            title(app.UIAxes3_3, '')
            xlabel(app.UIAxes3_3, '')
            ylabel(app.UIAxes3_3, '')
            app.UIAxes3_3.XTick = [];
            app.UIAxes3_3.YTick = [];
            app.UIAxes3_3.Visible = 'off';
            app.PSDButton_2 = uibutton(app.IIR, 'push');
            app.PSDButton_2.ButtonPushedFcn = createCallbackFcn(app, @PSDButton_2Pushed2, true);
            app.PSDButton_2.Text = 'PSD';
            app.FilteredReducedSGGButton_2 = uibutton(app.IIR, 'push');
            app.FilteredReducedSGGButton_2.ButtonPushedFcn = createCallbackFcn(app, @FilteredReducedSGGButton_2Pushed2, true);
            app.FilteredReducedSGGButton_2.Text = 'Filtered Reduced SGG';
            app.StatisticsButton_2 = uibutton(app.IIR, 'push');
            app.StatisticsButton_2.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButton_2Pushed2, true);
            app.StatisticsButton_2.Text = 'Statistics';
            app.MagnitudeResponseButton_2 = uibutton(app.IIR, 'push');
            app.MagnitudeResponseButton_2.ButtonPushedFcn = createCallbackFcn(app, @MagnitudeResponseButton_2Pushed2, true);
            app.MagnitudeResponseButton_2.Text = 'Magnitude Response';
            app.DesignMethodIIRButterworthLabel = uilabel(app.IIR);
            app.DesignMethodIIRButterworthLabel.Text = 'Design Method: IIR / Butterworth.';
            app.Lamp_18 = uilamp(app.IIR);
            app.Lamp_19 = uilamp(app.IIR);
            app.Lamp_20 = uilamp(app.IIR);
            app.Lamp_21 = uilamp(app.IIR);
            app.Vij_IIR_ReporttxtLabel = uilabel(app.IIR);
            app.Vij_IIR_ReporttxtLabel.Text = ' Vij_IIR_Report (.txt)';
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_5 = uilabel(app.IIR);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_5.Text = {'"Vij_IIR.mat"  &  "Vij_IIR_Report"'; 'were saved in your working folder'; 'automatically.'};
            app.Vij_IIRmatLabel = uilabel(app.IIR);
            app.Vij_IIRmatLabel.Text = ' Vij_IIR (.mat)';
            app.Lamp_22 = uilamp(app.IIR);
            app.Lamp_23 = uilamp(app.IIR);
            app.WaveletPanel = uipanel(app.SelectFilteringMethodPanel);
            app.WaveletPanel.Title = '- Display- ';
            app.WaveletPanel.Visible = 'off';
            app.WLMRAdecompositionreconstructionPanel = uipanel(app.WaveletPanel);
            app.WLMRAdecompositionreconstructionPanel.Title = 'WL MRA decomposition & reconstruction';
            app.d1CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d1CheckBox.Enable = 'off';
            app.d1CheckBox.Text = 'd1';
            app.d1CheckBox.Value = true;
            app.d2CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d2CheckBox.Enable = 'off';
            app.d2CheckBox.Text = 'd2';
            app.d2CheckBox.Value = true;
            app.d3CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d3CheckBox.Enable = 'off';
            app.d3CheckBox.Text = 'd3';
            app.d3CheckBox.Value = true;
            app.d4CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d4CheckBox.Enable = 'off';
            app.d4CheckBox.Text = 'd4';
            app.d4CheckBox.Value = true;
            app.d5CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d5CheckBox.Enable = 'off';
            app.d5CheckBox.Text = 'd5';
            app.d5CheckBox.Value = true;
            app.d6CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d6CheckBox.Enable = 'off';
            app.d6CheckBox.Text = 'd6';
            app.d6CheckBox.Value = true;
            app.d7CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d7CheckBox.Enable = 'off';
            app.d7CheckBox.Text = 'd7';
            app.d7CheckBox.Value = true;
            app.d8CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d8CheckBox.Enable = 'off';
            app.d8CheckBox.Text = 'd8';
            app.d8CheckBox.Value = true;
            app.d9CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d9CheckBox.Enable = 'off';
            app.d9CheckBox.Text = 'd9';
            app.d9CheckBox.Value = true;
            app.d10CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d10CheckBox.Enable = 'off';
            app.d10CheckBox.Text = 'd10';
            app.d10CheckBox.Value = true;
            app.d11CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d11CheckBox.Enable = 'off';
            app.d11CheckBox.Text = 'd11';
            app.d11CheckBox.Value = true;
            app.d12CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.d12CheckBox.Enable = 'off';
            app.d12CheckBox.Text = 'd12';
            app.d12CheckBox.Value = true;
            app.a12CheckBox = uicheckbox(app.WLMRAdecompositionreconstructionPanel);
            app.a12CheckBox.Enable = 'off';
            app.a12CheckBox.Text = 'a12';
            app.a12CheckBox.Value = true;
            app.WLMRAreconstructionButton = uibutton(app.WLMRAdecompositionreconstructionPanel, 'push');
            app.WLMRAreconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @WLMRAreconstructionButtonPushed, true);
            app.WLMRAreconstructionButton.Enable = 'off';
            app.WLMRAreconstructionButton.Text = 'WL MRA reconstruction';
            app.WLMRAdecompositionButton = uibutton(app.WLMRAdecompositionreconstructionPanel, 'push');
            app.WLMRAdecompositionButton.ButtonPushedFcn = createCallbackFcn(app, @WLMRAdecompositionButtonPushed, true);
            app.WLMRAdecompositionButton.Text = 'WL MRA decomposition';
            app.Lamp_GG_WL_MRA_3 = uilamp(app.WLMRAdecompositionreconstructionPanel);
            app.Lamp_GG_WL_MRA_2 = uilamp(app.WLMRAdecompositionreconstructionPanel);
            app.OriginalbrownandreconstructedgreensignalPanel = uipanel(app.WaveletPanel);
            app.OriginalbrownandreconstructedgreensignalPanel.Title = 'Original (brown) and reconstructed (green) signal';
            app.UIAxes_WL = uiaxes(app.OriginalbrownandreconstructedgreensignalPanel);
            title(app.UIAxes_WL, 'PSD of the Vzz gradient of the 1st orbit')
            xlabel(app.UIAxes_WL, 'Frequency (Hz)')
            ylabel(app.UIAxes_WL, 'Power')
            app.UIAxes_WL.Visible = 'off';
            app.UIAxes_WL_2 = uiaxes(app.OriginalbrownandreconstructedgreensignalPanel);
            title(app.UIAxes_WL_2, 'Vzz gradient of the 1st orbit')
            xlabel(app.UIAxes_WL_2, 'UTC (time)')
            ylabel(app.UIAxes_WL_2, 'Eötvös')
            app.UIAxes_WL_2.Visible = 'off';
            app.Lamp_GG_WL_MRA = uilamp(app.WaveletPanel);
            app.Lampexportreport_GG_WL_MRA = uilamp(app.WaveletPanel);
            app.GG_WLmatLabel = uilabel(app.WaveletPanel);
            app.GG_WLmatLabel.Enable = 'off';
            app.GG_WLmatLabel.Text = 'GG_WL (.mat)';
            app.GG_WL_ReportmatLabel = uilabel(app.WaveletPanel);
            app.GG_WL_ReportmatLabel.Enable = 'off';
            app.GG_WL_ReportmatLabel.Text = 'GG_WL_Report (.mat)';
            app.GGsafterWLMRAButton = uibutton(app.WaveletPanel, 'push');
            app.GGsafterWLMRAButton.ButtonPushedFcn = createCallbackFcn(app, @GGsafterWLMRAButtonPushed, true);
            app.GGsafterWLMRAButton.Enable = 'off';
            app.GGsafterWLMRAButton.Text = 'GGs after WL MRA';
            app.Lamp_GG_WL_MRA_plot = uilamp(app.WaveletPanel);
            app.statisticsGGWLmatReporttxtButton = uibutton(app.WaveletPanel, 'push');
            app.statisticsGGWLmatReporttxtButton.ButtonPushedFcn = createCallbackFcn(app, @statisticsGGWLmatReporttxtButtonPushed, true);
            app.statisticsGGWLmatReporttxtButton.Enable = 'off';
            app.statisticsGGWLmatReporttxtButton.Text = 'statistics GG WL (.mat)  & Report (.txt)';
            app.Lamp_stats_WL_MRA = uilamp(app.WaveletPanel);
            app.PSDsofGGsafterWLMRAButton = uibutton(app.WaveletPanel, 'push');
            app.PSDsofGGsafterWLMRAButton.ButtonPushedFcn = createCallbackFcn(app, @PSDsofGGsafterWLMRAButtonPushed, true);
            app.PSDsofGGsafterWLMRAButton.Enable = 'off';
            app.PSDsofGGsafterWLMRAButton.Text = 'PSDs of GGs after WL MRA';
            app.Lamp_psd_GG_WL_MRA_plot = uilamp(app.WaveletPanel);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_6 = uilabel(app.WaveletPanel);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_6.Text = {'GG_WL.mat"  &  "GG_WL_Report" '; 'were saved in your working folder automatically.'};
            app.SelectcoefficientsforsignalreconstructionLabel = uilabel(app.WaveletPanel);
            app.SelectcoefficientsforsignalreconstructionLabel.Text = 'Select coefficients for signal reconstruction';
            app.clearButton_2 = uibutton(app.FilteringTab, 'push');
            app.clearButton_2.ButtonPushedFcn = createCallbackFcn(app, @clearButton_2Pushed, true);
            app.clearButton_2.Text = 'clear';
            app.RSsTransformationsTab = uitab(app.TabGroup);
            app.RSsTransformationsTab.Title = 'RSs Transformations';
            app.TransformationGRFtoLNOFPanel = uipanel(app.RSsTransformationsTab);
            app.TransformationGRFtoLNOFPanel.Title = ' - Transformation GRF to LNOF -';
            app.TransformationGRFtoLNOFButton = uibutton(app.TransformationGRFtoLNOFPanel, 'push');
            app.TransformationGRFtoLNOFButton.ButtonPushedFcn = createCallbackFcn(app, @TransformationGRFtoLNOFButtonPushed, true);
            app.TransformationGRFtoLNOFButton.Enable = 'off';
            app.TransformationGRFtoLNOFButton.Text = 'Transformation GRF to LNOF';
            app.LoaddataforGRFtoLNOFtransformationButton = uibutton(app.TransformationGRFtoLNOFPanel, 'push');
            app.LoaddataforGRFtoLNOFtransformationButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataforGRFtoLNOFtransformationButtonPushed, true);
            app.LoaddataforGRFtoLNOFtransformationButton.Text = 'Load data for GRF to LNOF transformation';
            app.LampdataGRFtoLNOF = uilamp(app.TransformationGRFtoLNOFPanel);
            app.LamptransformationGRFtoLNOF = uilamp(app.TransformationGRFtoLNOFPanel);
            app.ImporttheGGMgravitationaltensorcomponentsGrafLabsoutputLabel_3 = uilabel(app.TransformationGRFtoLNOFPanel);
            app.ImporttheGGMgravitationaltensorcomponentsGrafLabsoutputLabel_3.Text = {'Import data (format lat,lon,h,UTC time,name of current day '; 'Vxx,Vyy,Vzz,Vxy, Vxz,Vyz,q1,q2,q3,q4 (IRF to GRF),'; 'q1,q2,q3,q4 (EFRF to IRF) )'};
            app.StatisticsofthedatainLNOFmatReportButton = uibutton(app.TransformationGRFtoLNOFPanel, 'push');
            app.StatisticsofthedatainLNOFmatReportButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsofthedatainLNOFmatReportButtonPushed, true);
            app.StatisticsofthedatainLNOFmatReportButton.Enable = 'off';
            app.StatisticsofthedatainLNOFmatReportButton.Text = 'Statistics of the data in LNOF(.mat) & Report';
            app.LampstatstransfLNOF = uilamp(app.TransformationGRFtoLNOFPanel);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_9 = uilabel(app.TransformationGRFtoLNOFPanel);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_9.Text = 'Export Statistics & Figures (.jpeg, .fig):';
            app.FiguresofthedatainLNOFButton = uibutton(app.TransformationGRFtoLNOFPanel, 'push');
            app.FiguresofthedatainLNOFButton.ButtonPushedFcn = createCallbackFcn(app, @FiguresofthedatainLNOFButtonPushed, true);
            app.FiguresofthedatainLNOFButton.Enable = 'off';
            app.FiguresofthedatainLNOFButton.Text = 'Figures of the data in LNOF ';
            app.LampfigtransfLNOF = uilamp(app.TransformationGRFtoLNOFPanel);
            app.DisplayPanel_3 = uipanel(app.RSsTransformationsTab);
            app.DisplayPanel_3.Title = '- Display -';
            app.UIAxes_Vzz_LNOF_3 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_Vzz_LNOF_3, 'Gravity Gradient - Vzz - LNOF')
            xlabel(app.UIAxes_Vzz_LNOF_3, 'UTC (time)')
            ylabel(app.UIAxes_Vzz_LNOF_3, 'Eötvös')
            app.UIAxes_Vzz_LNOF_3.Visible = 'off';
            app.UIAxes_Vzz_GRF_3 = uiaxes(app.DisplayPanel_3);
            title(app.UIAxes_Vzz_GRF_3, 'Gravity Gradient - Vzz - GRF')
            xlabel(app.UIAxes_Vzz_GRF_3, 'UTC (time)')
            ylabel(app.UIAxes_Vzz_GRF_3, 'Eötvös')
            app.UIAxes_Vzz_GRF_3.Visible = 'off';
            app.DisplayPanel_4 = uipanel(app.RSsTransformationsTab);
            app.DisplayPanel_4.Title = '- Display -';
            app.UIAxes_Vzz_LNOF_4 = uiaxes(app.DisplayPanel_4);
            title(app.UIAxes_Vzz_LNOF_4, 'Gravity Gradient - Vzz - LNOF')
            xlabel(app.UIAxes_Vzz_LNOF_4, 'UTC (time)')
            ylabel(app.UIAxes_Vzz_LNOF_4, 'Eötvös')
            app.UIAxes_Vzz_LNOF_4.Visible = 'off';
            app.UIAxes_Vzz_GRF_4 = uiaxes(app.DisplayPanel_4);
            title(app.UIAxes_Vzz_GRF_4, 'Gravity Gradient - Vzz - GRF')
            xlabel(app.UIAxes_Vzz_GRF_4, 'UTC (time)')
            ylabel(app.UIAxes_Vzz_GRF_4, 'Eötvös')
            app.UIAxes_Vzz_GRF_4.Visible = 'off';
            app.TransformationLNOFtoGRFPanel = uipanel(app.RSsTransformationsTab);
            app.TransformationLNOFtoGRFPanel.Title = ' - Transformation LNOF to GRF -';
            app.TransformationLNOFtoGRFButton = uibutton(app.TransformationLNOFtoGRFPanel, 'push');
            app.TransformationLNOFtoGRFButton.ButtonPushedFcn = createCallbackFcn(app, @TransformationLNOFtoGRFButtonPushed, true);
            app.TransformationLNOFtoGRFButton.Enable = 'off';
            app.TransformationLNOFtoGRFButton.Text = 'Transformation LNOF to GRF';
            app.LoaddataforLNOFtoGRFtransformationButton = uibutton(app.TransformationLNOFtoGRFPanel, 'push');
            app.LoaddataforLNOFtoGRFtransformationButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataforLNOFtoGRFtransformationButtonPushed, true);
            app.LoaddataforLNOFtoGRFtransformationButton.Text = 'Load data for LNOF to GRF transformation';
            app.LampdataLNOFtoGRF = uilamp(app.TransformationLNOFtoGRFPanel);
            app.LamptransformationLNOFtoGRF = uilamp(app.TransformationLNOFtoGRFPanel);
            app.StatisticsofthedatainGRFmatReportButton = uibutton(app.TransformationLNOFtoGRFPanel, 'push');
            app.StatisticsofthedatainGRFmatReportButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsofthedatainGRFmatReportButtonPushed, true);
            app.StatisticsofthedatainGRFmatReportButton.Enable = 'off';
            app.StatisticsofthedatainGRFmatReportButton.Text = 'Statistics of the data in GRF(.mat) & Report';
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_10 = uilabel(app.TransformationLNOFtoGRFPanel);
            app.These2fileswillbesavedinyourworkingfolderautomaticallyLabel_10.Text = 'Export Statistics & Figures (.jpeg, .fig):';
            app.FiguresofthedatainGRFButton = uibutton(app.TransformationLNOFtoGRFPanel, 'push');
            app.FiguresofthedatainGRFButton.ButtonPushedFcn = createCallbackFcn(app, @FiguresofthedatainGRFButtonPushed, true);
            app.FiguresofthedatainGRFButton.Enable = 'off';
            app.FiguresofthedatainGRFButton.Text = 'Figures of the data in GRF';
            app.ImporttheGGMgravitationaltensorcomponentsGrafLabsoutputLabel_4 = uilabel(app.TransformationLNOFtoGRFPanel);
            app.ImporttheGGMgravitationaltensorcomponentsGrafLabsoutputLabel_4.Text = {'Import data (format lat,lon,h,UTC time,name of current day '; 'Vxx,Vyy,Vzz,Vxy, Vxz,Vyz,q1,q2,q3,q4 (EFRF to IRF),'; 'q1,q2,q3,q4 (IRF to GRF))'};
            app.LampstatstransfGRF = uilamp(app.TransformationLNOFtoGRFPanel);
            app.LampFIGtransfGRF = uilamp(app.TransformationLNOFtoGRFPanel);
            app.GravLabAUTh2020Label = uilabel(app.GeoGravGOCEFigure);
            app.GravLabAUTh2020Label.Text = 'GravLab, AUTh, 2020';
            app.Image = uiimage(app.GeoGravGOCEFigure);
            app.Image.ImageSource = 'GeoGravGOCE_Logo.jpg';
            % Show the figure after all components are created
            app.GeoGravGOCEFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = geogravgoce

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeoGravGOCEFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeoGravGOCEFigure)
        end
    end
end